classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TabGroup             matlab.ui.container.TabGroup
        MainTab              matlab.ui.container.Tab
        TeachTab             matlab.ui.container.Tab
        YButton_4            matlab.ui.control.Button
        ZButton_4            matlab.ui.control.Button
        ZButton_3            matlab.ui.control.Button
        XButton_4            matlab.ui.control.Button
        YButton_3            matlab.ui.control.Button
        XButton_3            matlab.ui.control.Button
        YButton_2            matlab.ui.control.Button
        ZButton              matlab.ui.control.Button
        ZButton_2            matlab.ui.control.Button
        XButton_2            matlab.ui.control.Button
        YButton              matlab.ui.control.Button
        XButton              matlab.ui.control.Button
        Button_12            matlab.ui.control.Button
        Button_11            matlab.ui.control.Button
        Button_10            matlab.ui.control.Button
        Button_9             matlab.ui.control.Button
        Button_8             matlab.ui.control.Button
        Button_7             matlab.ui.control.Button
        Button_6             matlab.ui.control.Button
        Button_5             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        Button_2             matlab.ui.control.Button
        Button               matlab.ui.control.Button
        Wrist3Display        matlab.ui.control.EditField
        Wrist2Display        matlab.ui.control.EditField
        Wrist1Display        matlab.ui.control.EditField
        ElbowDisplay         matlab.ui.control.EditField
        ShoulderDisplay      matlab.ui.control.EditField
        BaseDisplay          matlab.ui.control.EditField
        YawEditField         matlab.ui.control.EditField
        YawEditFieldLabel    matlab.ui.control.Label
        PitchEditField       matlab.ui.control.EditField
        PitchEditFieldLabel  matlab.ui.control.Label
        RollEditField        matlab.ui.control.EditField
        RollEditFieldLabel   matlab.ui.control.Label
        ZEditField_2         matlab.ui.control.EditField
        ZEditField_2Label    matlab.ui.control.Label
        YEditField_2         matlab.ui.control.EditField
        YEditField_2Label    matlab.ui.control.Label
        XEditField_2         matlab.ui.control.EditField
        XEditField_2Label    matlab.ui.control.Label
        STARTButton          matlab.ui.control.Button
        Wrist3Slider         matlab.ui.control.Slider
        Wrist3SliderLabel    matlab.ui.control.Label
        Wrist2Slider         matlab.ui.control.Slider
        Wrist2SliderLabel    matlab.ui.control.Label
        Wrist1Slider         matlab.ui.control.Slider
        Wrist1SliderLabel    matlab.ui.control.Label
        ElbowSlider          matlab.ui.control.Slider
        ElbowSliderLabel     matlab.ui.control.Label
        ShoulderSlider       matlab.ui.control.Slider
        ShoulderSliderLabel  matlab.ui.control.Label
        BaseSlider           matlab.ui.control.Slider
        BaseSliderLabel      matlab.ui.control.Label
        Switch               matlab.ui.control.Switch
        SwitchLabel          matlab.ui.control.Label
        UIAxes               matlab.ui.control.UIAxes
    end


    % ------------- Private Property of this GUI -------------------------%
    properties (Access = private)

        % ---- 1. Create robot models below:
        % Create a robot model for teaching tasks:
        tm5Teach;
        qHome = [0, -pi/2, -pi/2, -pi/2, pi/2, 0];

        % ---- 2.

        % ---- Other Data member:
        handle;
    end

    methods (Access = private)

        %% Update the End Effector Pose (Used for TM5 Teach):
        function UpdateEndEffectorDisplay(app, q)

            % --- Get the forward kinematic of the robot the get the pose of the end-effector:
            T = app.tm5Teach.model.fkine(q).T;

            % -- Get the current position and orientation:
            position = T(1:3, 4);
            orientation = tr2eul(T);

            % Update Position Edit Fields
            app.XEditField_2.Value = num2str(position(1));
            app.YEditField_2.Value = num2str(position(2));
            app.ZEditField_2.Value = num2str(position(3));

            % Update Orientation Edit Fields
            app.RollEditField.Value = num2str(orientation(1));
            app.PitchEditField.Value = num2str(orientation(2));
            app.YawEditField.Value = num2str(orientation(3));

        end

        %% Update the Slider Value Display (Used for TM5 Teach)
        function UpdateSliderValueDisplay(app)

            % --- Get the value of the slider
            app.BaseDisplay.Value = num2str(app.BaseSlider.Value);
            app.ShoulderDisplay.Value = num2str(app.ShoulderSlider.Value);
            app.ElbowDisplay.Value = num2str(app.ElbowSlider.Value);
            app.Wrist1Display.Value = num2str(app.Wrist1Slider.Value);
            app.Wrist2Display.Value = num2str(app.Wrist2Slider.Value);
            app.Wrist3Display.Value = num2str(app.Wrist3Slider.Value);

        end

        %% Find Handle in the GUI:
        function FindHandleTM5(app)
            app.handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.tm5Teach.model.name)
                    app.handle = app.UIAxes.Children(childIndex);
                end
            end

        end
        
        %% Update the robot when the slider change:
        function ChangeSlider(app, value, index)
            % Update the qHome based on the slider value
            app.qHome(index) = deg2rad(value);

            % Display the value:
            app.UpdateSliderValueDisplay;

            % Find handle and Animate the Q
            app.FindHandleTM5();
            app.AnimateModfiled(app.tm5Teach, app.qHome, app.handle);
            drawnow;

            % Update the value:
            app.UpdateEndEffectorDisplay(app.qHome);
        end

        %% Animate Modfiled (Used for all model)
        function AnimateModfiled(app, robot, qq, handles)


            if nargin < 3
                handles = findobj('Tag', robot.name);
            end

            robot = robot.model;

            links = robot.links;
            N = robot.n;

            % MAIN DISPLAY/ANIMATION LOOP
            while true
                % animate over all instances of this robot in different axes

                for q=qq'  % for all configurations in trajectory
                    q = q';
                    for handle=handles'
                        h = get(handle, 'UserData');

                        debug = false;
                        if debug
                            disp('ROBOT LINKS')
                            disp(links)
                            disp('H')
                            disp(h)
                            disp('H.WRIST')
                            disp(h.wrist)
                            disp('H.WRIST.CHILDREN')
                            disp(h.wrist.Children)
                            disp('H.LINK')
                            disp(h.link)
                        end

                        % now draw it for a pose q
                        if robot.mdh
                            % modified DH case
                            T = robot.base;
                            vert = T.t';

                            for L=1:N
                                if robot.links(L).isprismatic()
                                    % scale the box representing the prismatic joint
                                    % it is based at the origin and extends in z-direction
                                    if q(L) > 0
                                        set(h.pjoint(L), 'Matrix', diag([1 1 q(L) 1]));
                                    else
                                        % if length is zero the matrix is singular and MATLAB complains
                                        %error('Prismatic length must be > 0');
                                    end
                                end
                                T = T * links(L).A(q(L));
                                set(h.link(L), 'Matrix', T.T);
                                vert = [vert; T.t'];
                            end
                            % update the transform for link N+1 (the tool)
                            T = T * robot.tool;
                            if length(h.link) > N
                                set(h.link(N+1), 'Matrix', T.T);
                            end
                            vert = [vert; T.t'];
                        else
                            % standard DH case
                            T = robot.base;
                            vert = T.t';

                            for L=1:N
                                % for all N links

                                if robot.links(L).isprismatic()
                                    %disp(['q(L) = ', num2str(q(L))])
                                    % scale the box representing the prismatic joint
                                    % it is based at the origin and extends in z-direction

                                    % Looking at the way this pjoint issue was
                                    % solved in the V9 code, the error was simply
                                    % commented out.
                                    scicEdit = true;

                                    if ~scicEdit
                                        assert( q(L) >= 0, 'Prismatic joint length must be >= 0'); % link lengths must be positive

                                        % if scale factor is zero the matrix is singular and MATLAB complains
                                        %  so we make it no smaller than eps

                                        set(h.pjoint(L), 'Matrix', diag([1 1 max(eps, q(L)) 1]));
                                    else
                                        if q(L) > 0
                                            set(h.pjoint(L), 'Matrix', diag([1 1 q(L) 1]));
                                        else
                                            % if length is zero the matrix is singular and MATLAB complains
                                            %error('Prismatic length must be > 0');
                                        end
                                    end

                                end

                                % now set the transform for frame {L}, this controls the displayed pose of:
                                %   the pipes associated with link L, that join {L} back to {L-1}
                                %   (optional) a prismatic joint L, that joins {L} to {L+1}
                                if h.link(L) ~= 0
                                    % for plot3d, skip any 0 in the handle list
                                    set(h.link(L), 'Matrix', T.T);
                                end

                                T = T * links(L).A(q(L));
                                vert = [vert; T.t'];
                            end
                            % update the transform for link N+1 (the tool)
                            T = T*robot.tool;
                            if length(h.link) > N
                                set(h.link(N+1), 'Matrix', T.T);
                            end

                            vert = [vert; T.t'];
                        end

                        % now draw the shadow
                        if isfield(h, 'shadow')
                            set(h.shadow, 'Xdata', vert(:,1), 'Ydata', vert(:,2), ...
                                'Zdata', h.floorlevel*ones(size(vert(:,1))));
                        end

                        % update the tool tip trail
                        if isfield(h, 'trail')
                            T = robot.fkine(q);
                            robot.trail = [robot.trail; transl(T)];
                            set(h.trail, 'Xdata', robot.trail(:,1), 'Ydata', robot.trail(:,2), 'Zdata', robot.trail(:,3));
                        end

                        % animate the wrist frame
                        if ~isempty(h.wrist)
                            trplot(T, 'handle', h.wrist);
                        end

                        % add a frame to the movie
                        if ~isempty(h.robot.movie)
                            h.robot.movie.add();
                        end

                        if h.robot.delay > 0
                            pause(h.robot.delay);
                            drawnow
                        end
                        h.q = q;
                        set(handle, 'UserData', h);
                    end
                end

                try
                    if ~h.robot.loop
                        break;
                    end
                catch
                    warning(['in animate(). It is likely that there is no robot plot handle named: ''',robot.name,''' in any open figures. Has it been deleted?'])
                    break;
                end
            end

        end
        
        %% ControlByEndEffectorPosition:
        function ControlByEndEffectorPosition(app, point1, point2)
        
            % Initial parameter
            t = 2;                              % Total Time of motion
            steps = 50;                         % Number of steps
            deltaT = t/steps;                   % Small Angle Change
            threshold = 0.2;                    % Threshhold value for manipulability
            waypoints = zeros(steps,3);         % Waypoints matrix

            % Linear interpolation between the two points
            for i = 1:steps
                t = (i - 1) / (steps - 1);  % Interpolation parameter [0, 1]
                waypoints(i, :) = (1 - t) * point1 + t * point2;
            end

            % Create a matrix of joint angles
            qMatrix = nan(steps,app.tm5Teach.model.n);

            % Get the initial Joint Angles:
            qMatrix(1, :) = app.qHome;

            % Get the desired orientation of the end-effector:
            desiredPose = app.tm5Teach.model.fkine(app.qHome).T;
            desiredOre  = desiredPose(1:3, 1:3);

            % Track the trajectory with RMRC:
            for i = 1:steps-1

                % Find the linear velocity vector at this current pose:
                xdot = (waypoints(i + 1, :) - waypoints(i, :)) / deltaT;

                % Find the Jacobian Matrix at the current pose:
                J = app.tm5Teach.model.jacob0(qMatrix(i,:));

                % Calculate the different between the current pose and the desired pose:
                currentPose = app.tm5Teach.model.fkine(qMatrix(i,:)).T;
                currentOre = currentPose(1:3,1:3);

                % Find the rotation matrix between the desired pose and the current:
                rotationMatrix = desiredOre*currentOre';

                % Convert the rotation matrix to a rotation vector (axis-angle representation)
                angle = rotm2axang(rotationMatrix);

                % Convert the rotation vector to a 3x1 angular error vector
                orientationDiff = angle(:,1:3)'*angle(:,4);

                % Combine Velocity:
                combinedVelocity = [xdot';  orientationDiff];

                % Measure of Manipulability
                mu = sqrt(det(J*J'));

                % If manipulability is less than given threshold
                if mu < threshold
                    % Damping coefficient (example value)
                    lambda = 0.01;

                    % Apply Damped Least Squares pseudoinverse
                    invJ = (J'*J + lambda^2*eye(6))\J';
                else
                    invJ = pinv(J);
                end

                % Solve velocities via RMRC:
                qdot = invJ*combinedVelocity;

                % Update next joint state based on joint velocities:
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qdot';
                app.qHome = qMatrix(i+1,:);

                % Animate the path:
                app.FindHandleTM5();
                app.AnimateModfiled(app.tm5Teach, qMatrix(i+1,:), app.handle);

                % Update the slider:
                app.BaseSlider.Value = rad2deg(app.qHome(1));
                app.ShoulderSlider.Value = rad2deg(app.qHome(2));
                app.ElbowSlider.Value = rad2deg(app.qHome(3));
                app.Wrist1Slider.Value = rad2deg(app.qHome(4));
                app.Wrist2Slider.Value = rad2deg(app.qHome(5));
                app.Wrist3Slider.Value = rad2deg(app.qHome(6));

                % Update Label:
                app.UpdateEndEffectorDisplay(app.qHome);
                app.UpdateSliderValueDisplay;

            end
        end

        %% ControlByEndEffectorOrientation:
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

            % Get the switch value:
            switchValue = app.Switch.Value;

            % Disable the slider when switch is on
            if strcmp(switchValue, 'On')
                app.BaseSlider.Enable = "off";
                app.ShoulderSlider.Enable = "off";
                app.ElbowSlider.Enable = "off";
                app.Wrist1Slider.Enable = "off";
                app.Wrist2Slider.Enable = "off";
                app.Wrist3Slider.Enable = "off";
            else
                app.BaseSlider.Enable = "on";
                app.ShoulderSlider.Enable = "on";
                app.ElbowSlider.Enable = "on";
                app.Wrist1Slider.Enable = "on";
                app.Wrist2Slider.Enable = "on";
                app.Wrist3Slider.Enable = "on";
            end

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            % --- Setup the axis of the app:
            axes(app.UIAxes);
            cla(app.UIAxes);
            ax = gca;

            % --- Create a robot model when the button is pressed:
            app.tm5Teach = TM5(eye(4), app.qHome, [-1.5 1.5, -1.5, 1.5, 0, 2]);
            drawnow

            % --- Update the data of the robot when the robot is called:
            app.UpdateEndEffectorDisplay(app.qHome);

            % --- Set up the slider limit based on the robot limit:
            % Turn on the slider if the start button is pressed:
            app.BaseSlider.Enable = 'on';
            app.ShoulderSlider.Enable = 'on';
            app.ElbowSlider.Enable = 'on';
            app.Wrist1Slider.Enable = 'on';
            app.Wrist2Slider.Enable = 'on';
            app.Wrist3Slider.Enable = 'on';
            
            % Get the joint limit of the robot:
            jointLimits = rad2deg(app.tm5Teach.model.qlim);

            % Update the Slider Limits:
            app.BaseSlider.Limits = jointLimits(1,:);       % Set the limits for the Base joint
            app.ShoulderSlider.Limits = jointLimits(2,:);   % Set the limits for the Shoulder joint
            app.ElbowSlider.Limits = jointLimits(3,:);      % Set the limits for the Elbow joint
            app.Wrist1Slider.Limits = jointLimits(4,:);     % Set the limits for the Wrist 1 joint
            app.Wrist2Slider.Limits = jointLimits(5,:);     % Set the limits for the Wrist 2 joint
            app.Wrist3Slider.Limits = jointLimits(6,:);     % Set the limits for the Wrist 3 joint

            % Update the Slider Value at the initial stage:
            % Get the current q:
            currentQ = rad2deg(app.qHome);

            % Update the Slider Value from the current joint stage:
            app.BaseSlider.Value = currentQ(1);
            app.ShoulderSlider.Value = currentQ(2);
            app.ElbowSlider.Value = currentQ(3);
            app.Wrist1Slider.Value = currentQ(4);
            app.Wrist2Slider.Value = currentQ(5);
            app.Wrist3Slider.Value = currentQ(6);

            % Update the value to the label:
            app.UpdateSliderValueDisplay;
            
            % --- Turn on the switch:
            app.Switch.Enable = 'on';

            % --- Turn on the end effector control button:
            app.XButton.Enable = 'on';
            app.YButton.Enable = 'on';
            app.ZButton.Enable = 'on';
            app.XButton_2.Enable = 'on';
            app.YButton_2.Enable = 'on';
            app.ZButton_2.Enable = 'on';


            % --- Display the robot in the axes of the app:
            % - Delete the green floor surface:
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end

            % - Transfer all the children across to the uiaxes parent EXCEPT the green floors
            while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
            end
            delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];

            % Adjust the axes limits
            app.UIAxes.XLim = [-0.5, 0.5];
            app.UIAxes.YLim = [-0.5, 0.5];
            app.UIAxes.ZLim = [-0.2, 0.8];


            % Turn off this load button and enable the move button
            app.STARTButton.Enable = "off";


        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            app.ChangeSlider(app.BaseSlider.Value, 1);
        end

        % Value changed function: ShoulderSlider
        function ShoulderSliderValueChanged(app, event)
            app.ChangeSlider(app.ShoulderSlider.Value, 2);
        end

        % Value changed function: ElbowSlider
        function ElbowSliderValueChanged(app, event)
            app.ChangeSlider(app.ElbowSlider.Value, 3);
        end

        % Value changed function: Wrist1Slider
        function Wrist1SliderValueChanged(app, event)
            app.ChangeSlider(app.Wrist1Slider.Value, 4);
        end

        % Value changed function: Wrist2Slider
        function Wrist2SliderValueChanged(app, event)
            app.ChangeSlider(app.Wrist2Slider.Value, 5);
        end

        % Value changed function: Wrist3Slider
        function Wrist3SliderValueChanged(app, event)
            app.ChangeSlider(app.Wrist3Slider.Value, 6);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.BaseSlider.Value < app.BaseSlider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.BaseSlider.Value = app.BaseSlider.Value + 1;
                app.ChangeSlider(app.BaseSlider.Value, 1);
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.BaseSlider.Value > app.BaseSlider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.BaseSlider.Value = app.BaseSlider.Value - 1;
                app.ChangeSlider(app.BaseSlider.Value, 1);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.ShoulderSlider.Value < app.ShoulderSlider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.ShoulderSlider.Value = app.ShoulderSlider.Value + 1;
                app.ChangeSlider(app.ShoulderSlider.Value, 2);
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.ShoulderSlider.Value > app.ShoulderSlider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.ShoulderSlider.Value = app.ShoulderSlider.Value - 1;
                app.ChangeSlider(app.ShoulderSlider.Value, 2);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.ElbowSlider.Value < app.ElbowSlider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.ElbowSlider.Value = app.ElbowSlider.Value + 1;
                app.ChangeSlider(app.ElbowSlider.Value, 3);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.ElbowSlider.Value > app.ElbowSlider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.ElbowSlider.Value = app.ElbowSlider.Value - 1;
                app.ChangeSlider(app.ElbowSlider.Value, 3);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.Wrist1Slider.Value < app.Wrist1Slider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist1Slider.Value = app.Wrist1Slider.Value + 1;
                app.ChangeSlider(app.Wrist1Slider.Value, 4);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.Wrist1Slider.Value > app.Wrist1Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist1Slider.Value = app.Wrist1Slider.Value - 1;
                app.ChangeSlider(app.Wrist1Slider.Value, 4);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.Wrist2Slider.Value < app.Wrist2Slider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist2Slider.Value = app.Wrist2Slider.Value + 1;
                app.ChangeSlider(app.Wrist2Slider.Value, 5);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.Wrist2Slider.Value > app.Wrist2Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist2Slider.Value = app.Wrist2Slider.Value - 1;
                app.ChangeSlider(app.Wrist2Slider.Value, 5);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.Wrist3Slider.Value > app.Wrist3Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist3Slider.Value = app.Wrist3Slider.Value + 1;
                app.ChangeSlider(app.Wrist3Slider.Value, 6);
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.Wrist3Slider.Value > app.Wrist3Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist3Slider.Value = app.Wrist3Slider.Value - 1;
                app.ChangeSlider(app.Wrist3Slider.Value, 6);
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Get the forward Kinematic
            tr = app.tm5Teach.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            XDirection = tr(1:3,1)';
            point2 = point1 + 0.01*XDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Get the forward Kinematic
            tr = app.tm5Teach.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            XDirection = tr(1:3,1)';
            point2 = point1 - 0.01*XDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Get the forward Kinematic
            tr = app.tm5Teach.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            YDirection = tr(1:3,2)';
            point2 = point1 - 0.01*YDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Get the forward Kinematic
            tr = app.tm5Teach.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            YDirection = tr(1:3,2)';
            point2 = point1 + 0.01*YDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Get the forward Kinematic
            tr = app.tm5Teach.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            ZDirection = tr(1:3,3)';
            point2 = point1 + 0.01*ZDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Get the forward Kinematic
            tr = app.tm5Teach.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            ZDirection = tr(1:3,3)';
            point2 = point1 - 0.01*ZDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.TeachTab = uitab(app.TabGroup);
            app.TeachTab.Title = 'Teach';
            app.UIAxes = uiaxes(app.TeachTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SwitchLabel = uilabel(app.TeachTab);
            app.SwitchLabel.Enable = 'off';
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.TeachTab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.BaseSliderLabel = uilabel(app.TeachTab);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.TeachTab);
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.Enable = 'off';
            app.ShoulderSliderLabel = uilabel(app.TeachTab);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.TeachTab);
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderSliderValueChanged, true);
            app.ShoulderSlider.Enable = 'off';
            app.ElbowSliderLabel = uilabel(app.TeachTab);
            app.ElbowSliderLabel.Text = 'Elbow';
            app.ElbowSlider = uislider(app.TeachTab);
            app.ElbowSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowSliderValueChanged, true);
            app.ElbowSlider.Enable = 'off';
            app.Wrist1SliderLabel = uilabel(app.TeachTab);
            app.Wrist1SliderLabel.Text = 'Wrist 1';
            app.Wrist1Slider = uislider(app.TeachTab);
            app.Wrist1Slider.ValueChangedFcn = createCallbackFcn(app, @Wrist1SliderValueChanged, true);
            app.Wrist1Slider.Enable = 'off';
            app.Wrist2SliderLabel = uilabel(app.TeachTab);
            app.Wrist2SliderLabel.Text = 'Wrist 2';
            app.Wrist2Slider = uislider(app.TeachTab);
            app.Wrist2Slider.ValueChangedFcn = createCallbackFcn(app, @Wrist2SliderValueChanged, true);
            app.Wrist2Slider.Enable = 'off';
            app.Wrist3SliderLabel = uilabel(app.TeachTab);
            app.Wrist3SliderLabel.Text = 'Wrist 3';
            app.Wrist3Slider = uislider(app.TeachTab);
            app.Wrist3Slider.ValueChangedFcn = createCallbackFcn(app, @Wrist3SliderValueChanged, true);
            app.Wrist3Slider.Enable = 'off';
            app.STARTButton = uibutton(app.TeachTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.XEditField_2Label = uilabel(app.TeachTab);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.TeachTab, 'text');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.TeachTab);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.TeachTab, 'text');
            app.YEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.TeachTab);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.TeachTab, 'text');
            app.ZEditField_2.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.TeachTab);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.TeachTab, 'text');
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.TeachTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.TeachTab, 'text');
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.TeachTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.TeachTab, 'text');
            app.YawEditField.Editable = 'off';
            app.BaseDisplay = uieditfield(app.TeachTab, 'text');
            app.BaseDisplay.Editable = 'off';
            app.ShoulderDisplay = uieditfield(app.TeachTab, 'text');
            app.ShoulderDisplay.Editable = 'off';
            app.ElbowDisplay = uieditfield(app.TeachTab, 'text');
            app.ElbowDisplay.Editable = 'off';
            app.Wrist1Display = uieditfield(app.TeachTab, 'text');
            app.Wrist1Display.Editable = 'off';
            app.Wrist2Display = uieditfield(app.TeachTab, 'text');
            app.Wrist2Display.Editable = 'off';
            app.Wrist3Display = uieditfield(app.TeachTab, 'text');
            app.Wrist3Display.Editable = 'off';
            app.Button = uibutton(app.TeachTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.TeachTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.TeachTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.TeachTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '+';
            app.Button_5 = uibutton(app.TeachTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.TeachTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '+';
            app.Button_7 = uibutton(app.TeachTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.TeachTab, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-';
            app.Button_9 = uibutton(app.TeachTab, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '-';
            app.Button_10 = uibutton(app.TeachTab, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-';
            app.Button_11 = uibutton(app.TeachTab, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-';
            app.Button_12 = uibutton(app.TeachTab, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-';
            app.XButton = uibutton(app.TeachTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X+';
            app.YButton = uibutton(app.TeachTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Enable = 'off';
            app.YButton.Text = 'Y+';
            app.XButton_2 = uibutton(app.TeachTab, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Enable = 'off';
            app.XButton_2.Text = 'X-';
            app.ZButton_2 = uibutton(app.TeachTab, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Enable = 'off';
            app.ZButton_2.Text = 'Z-';
            app.ZButton = uibutton(app.TeachTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Enable = 'off';
            app.ZButton.Text = 'Z+';
            app.YButton_2 = uibutton(app.TeachTab, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Enable = 'off';
            app.YButton_2.Text = 'Y-';
            app.XButton_3 = uibutton(app.TeachTab, 'push');
            app.XButton_3.Enable = 'off';
            app.XButton_3.Text = 'X+';
            app.YButton_3 = uibutton(app.TeachTab, 'push');
            app.YButton_3.Enable = 'off';
            app.YButton_3.Text = 'Y+';
            app.XButton_4 = uibutton(app.TeachTab, 'push');
            app.XButton_4.Enable = 'off';
            app.XButton_4.Text = 'X-';
            app.ZButton_3 = uibutton(app.TeachTab, 'push');
            app.ZButton_3.Enable = 'off';
            app.ZButton_3.Text = 'Z-';
            app.ZButton_4 = uibutton(app.TeachTab, 'push');
            app.ZButton_4.Enable = 'off';
            app.ZButton_4.Text = 'Z+';
            app.YButton_4 = uibutton(app.TeachTab, 'push');
            app.YButton_4.Enable = 'off';
            app.YButton_4.Text = 'Y-';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end