classdef MainGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image4                      matlab.ui.control.Image
        TextArea                    matlab.ui.control.TextArea
        Lamp_8                      matlab.ui.control.Lamp
        Lamp_7                      matlab.ui.control.Lamp
        Image3                      matlab.ui.control.Image
        SensorDataPanel             matlab.ui.container.Panel
        EStop2EditField             matlab.ui.control.EditField
        EStop2EditFieldLabel        matlab.ui.control.Label
        Lamp_6                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        UltraSonicEditField         matlab.ui.control.EditField
        UltraSonicEditFieldLabel    matlab.ui.control.Label
        EStop1EditField             matlab.ui.control.EditField
        EStop1EditFieldLabel        matlab.ui.control.Label
        LightCurtainEditField       matlab.ui.control.EditField
        LightCurtainEditFieldLabel  matlab.ui.control.Label
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        EndEffectorDataPanel        matlab.ui.container.Panel
        VisualServoingButton        matlab.ui.control.Button
        BaseEditField_5             matlab.ui.control.EditField
        BaseEditField_3Label_3      matlab.ui.control.Label
        PEditField_2                matlab.ui.control.EditField
        PEditFieldLabel_2           matlab.ui.control.Label
        BaseEditField_4             matlab.ui.control.EditField
        BaseEditField_3Label_2      matlab.ui.control.Label
        PEditField                  matlab.ui.control.EditField
        PEditFieldLabel             matlab.ui.control.Label
        TM12Label_2                 matlab.ui.control.Label
        TM5Label_2                  matlab.ui.control.Label
        JointStateDataPanel         matlab.ui.container.Panel
        TM12Label                   matlab.ui.control.Label
        TM5Label                    matlab.ui.control.Label
        Wrist3EditField_2           matlab.ui.control.EditField
        Wrist3EditField_2Label      matlab.ui.control.Label
        Wrist2EditField_2           matlab.ui.control.EditField
        Wrist2EditField_2Label      matlab.ui.control.Label
        Wrist1EditField_2           matlab.ui.control.EditField
        Wrist1EditField_2Label      matlab.ui.control.Label
        ElbowEditField_2            matlab.ui.control.EditField
        ElbowEditField_2Label       matlab.ui.control.Label
        ShoulderEditField_2         matlab.ui.control.EditField
        ShoulderEditField_2Label    matlab.ui.control.Label
        BaseEditField_2             matlab.ui.control.EditField
        BaseEditField_2Label        matlab.ui.control.Label
        Wrist3EditField             matlab.ui.control.EditField
        Wrist3EditFieldLabel        matlab.ui.control.Label
        Wrist2EditField             matlab.ui.control.EditField
        Wrist2EditFieldLabel        matlab.ui.control.Label
        Wrist1EditField             matlab.ui.control.EditField
        Wrist1EditFieldLabel        matlab.ui.control.Label
        ElbowEditField              matlab.ui.control.EditField
        ElbowEditFieldLabel         matlab.ui.control.Label
        ShoulderEditField           matlab.ui.control.EditField
        ShoulderEditFieldLabel      matlab.ui.control.Label
        BaseEditField               matlab.ui.control.EditField
        BaseEditFieldLabel          matlab.ui.control.Label
        LoadProgramPanel            matlab.ui.container.Panel
        CancelButton                matlab.ui.control.Button
        EStopSwitch                 matlab.ui.control.ToggleSwitch
        EStopSwitchLabel            matlab.ui.control.Label
        ResumeButton                matlab.ui.control.Button
        EStopButton                 matlab.ui.control.Button
        RunMissionButton            matlab.ui.control.Button
        ObjectCollisionPanel        matlab.ui.container.Panel
        ArmSlider                   matlab.ui.control.Slider
        ArmSliderLabel              matlab.ui.control.Label
        HumanSlider                 matlab.ui.control.Slider
        HumanSliderLabel            matlab.ui.control.Label
        EnvironmentPanel            matlab.ui.container.Panel
        ResetEnvironmentButton      matlab.ui.control.Button
        Lamp_2                      matlab.ui.control.Lamp
        Lamp                        matlab.ui.control.Lamp
        TeachSwitch                 matlab.ui.control.ToggleSwitch
        TeachSwitchLabel            matlab.ui.control.Label
        TeachTM12Button             matlab.ui.control.Button
        LoadSimulateButton          matlab.ui.control.Button
        TeachTM5Button              matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Handle to the TeachPendant App:
        TeachPendantAppHandle = [];
        TeachPendantTM12AppHandle = [];

        % Data member of this class:
        tm5Robot;           %<! TM5 Robot
        tm12Robot;          %<! TM12 Robot
        human;              %<! Human Object
        arm;                %<! Arm Object

        % Setup the environment:
        tm5BaseTr  = transl(0,0,0.9)*trotz(pi);                  %<! The initial position for the TM5 Robot
        tm12BaseTr = transl(1.95,0.1,0.8);                       %<! The initial position for the TM12 Robot
        humanBaseTr = transl(1.5,-3.6,0) * trotz(-pi/2);         %<! The initial position for the human  
        armBaseTr = transl(2,1.15,1.1);                          %<! The initial position for the arm object

        % Setup the product
        products;

        % Setup the transform of the shelves in the environment:
        shelf1BaseTr = transl(-2,-2.55,0.1)*trotz(pi/2);
        shelf2BaseTr = transl(-1, -2.55,0.1)*trotz(pi/2);
        shelf3BaseTr = transl(0,-2.55,0.1)*trotz(pi/2);

        % Setup the homing position of the two robots:
        tm5qHome  = [pi, -pi/2, -pi/2, -pi/2, pi/2, 0];
        tm12qHome =  [0, -pi/2, -pi/2, -pi/2, pi/2, 0];
        
        % Setup the Lamp State:
        LampLightState = false;

        % Cancel Flag:
        cancelFlag = false;
    end
    
    methods (Access = public)
        
        %% UpdateJointStateData
        function UpdateJointStateData(app)
            
            % -- Get the current joint state of the two robot.
            currentPoseTM5  = rad2deg(app.tm5Robot.model.getpos);
            currentPoseTM12 = rad2deg(app.tm12Robot.model.getpos);

            % -- Update the the app label:
            % TM5 Robot:
            app.BaseEditField.Value = num2str(currentPoseTM5(1));
            app.ShoulderEditField.Value = num2str(currentPoseTM5(2));
            app.ElbowEditField.Value = num2str(currentPoseTM5(3));
            app.Wrist1EditField.Value = num2str(currentPoseTM5(4));
            app.Wrist2EditField.Value = num2str(currentPoseTM5(5));
            app.Wrist3EditField.Value = num2str(currentPoseTM5(6));
            
            % TM12 Robot:
            app.BaseEditField_2.Value = num2str(currentPoseTM12(1));
            app.ShoulderEditField_2.Value = num2str(currentPoseTM12(2));
            app.ElbowEditField_2.Value = num2str(currentPoseTM12(3));
            app.Wrist1EditField_2.Value = num2str(currentPoseTM12(4));
            app.Wrist2EditField_2.Value = num2str(currentPoseTM12(5));
            app.Wrist3EditField_2.Value = num2str(currentPoseTM12(6));
        end

        %% UpdateEndEffectorData
        function UpdateEndEffectorData(app)
            % -- Get the end effector data
            eeTrTM5 = app.tm5Robot.model.fkine(app.tm5Robot.model.getpos).T;
            eeTrTM12 = app.tm12Robot.model.fkine(app.tm12Robot.model.getpos).T;
    
            % -- Get the position:
            positionTM5 = eeTrTM5(1:3,4)';
            positionTM12 = eeTrTM12(1:3,4)';
            
            % -- Get the orientation:
            orientationTM5  = tr2rpy(eeTrTM5);
            orientationTM12 = tr2rpy(eeTrTM12);

            % -- Convert the position to a string format for display
            positionStrTM5  = sprintf('%.2f, %.2f, %.2f', positionTM5(1), positionTM5(2), positionTM5(3));
            positionStrTM12 = sprintf('%.2f, %.2f, %.2f', positionTM12(1), positionTM12(2), positionTM12(3));
            orientationTM5 = sprintf('%.2f, %.2f, %.2f', orientationTM5(1), orientationTM5(2), orientationTM5(3));
            orientationTM12 = sprintf('%.2f, %.2f, %.2f', orientationTM12(1), orientationTM12(2), orientationTM12(3));

            % -- Display on the edit field:
            app.PEditField.Value = positionStrTM5;
            app.PEditField_2.Value = positionStrTM12;
            app.BaseEditField_4.Value = orientationTM5;
            app.BaseEditField_5.Value = orientationTM12;
        end
        
        %% Toggle the Warning Lamp of the light Curtain
        function ToggleLampLight(app, index)

            if app.LampLightState == false
                if index == 1
                    app.Lamp_3.Color = [1 0 0];
                    app.LightCurtainEditField.Value = sprintf('Warning!!');
                    app.LampLightState = true;
                elseif index == 2
                    app.Lamp_5.Color = [1 0 0];
                    app.UltraSonicEditField.Value = sprintf('Warning!');
                    app.LampLightState = true;
                elseif index == 3
                    app.Lamp_6.Color = [1 0 0];
                    app.EStop2EditField.Value = sprintf('E-Stop Triggered!');
                    app.LampLightState = true;
                end
            else
                if index == 1
                    app.Lamp_3.Color = [1 1 0.8];
                    app.LampLightState = false;
                elseif index == 2
                    app.Lamp_5.Color = [1 1 0.8];
                    app.LampLightState = false;
                elseif index == 3
                    app.Lamp_6.Color = [1 0 0];
                    app.LampLightState = true;
                end
            end
        end

        %% Normalize the lamp after resuming the system:
        function NormalizeLamp(app)
            app.Lamp_3.Color = [0 1 0];
            app.Lamp_4.Color = [0 1 0];
            app.Lamp_5.Color = [0 1 0];
            app.Lamp_6.Color = [0 1 0];
            app.UltraSonicEditField.Value = sprintf('Safe');
            app.LightCurtainEditField.Value = sprintf('Safe');
            app.EStop1EditField.Value = sprintf('Normal');
            app.EStop2EditField.Value = sprintf('Normal');
        end
        
        %% Function when one of the safety activated!
        function ActivateSafetyMode(app)
            % Turn on the Switch Button
            app.EStopSwitch.Enable = "on";

            % Change the main light to Red:
            app.Lamp_7.Color = [1 0 0];
            app.Lamp_8.Color = [1 0 0];

            % Display on the text:
            app.TextArea.Value = sprintf("Safety Mode Activated!");
        end
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TeachTM5Button
        function TeachTM5ButtonPushed(app, event)
            app.TeachPendantAppHandle = TeachPendant(app, app.tm5Robot.model.getpos);
        end

        % Button pushed function: LoadSimulateButton
        function LoadSimulateButtonPushed(app, event)
            
            % -- Setup the workspace:
            SetupEnvironment(eye(4), app.shelf1BaseTr, app.shelf2BaseTr, app.shelf3BaseTr);
            hold  on;
            
            % -- Setup the robot:
            app.tm5Robot = TM5Mobile(app.tm5BaseTr, app.tm5qHome);
            app.tm12Robot = TM12(app.tm12BaseTr, app.tm12qHome);
            drawnow
            
            % -- Setup the products
            product1 = PlaceObjectModified('redProduct.ply', app.tm12BaseTr*transl(0.5, 0, 0.05));
            product2 = PlaceObjectModified('blueProduct.ply', app.tm12BaseTr*transl(0.5, 0.25, 0.05));
            product3 = PlaceObjectModified('greenProduct.ply', app.tm12BaseTr*transl(0.5, -0.25, 0.05));
            product4 = PlaceObjectModified('redProduct.ply', app.tm12BaseTr*transl(0.75, 0, 0.05));
            product5 = PlaceObjectModified('blueProduct.ply', app.tm12BaseTr*transl(0.75, 0.25, 0.05));
            product6 = PlaceObjectModified('greenProduct.ply', app.tm12BaseTr*transl(0.75, -0.25, 0.05));

            app.products= {product1, product2, product3, product4, product5, product6};

            % -- Create object human:
            app.human = Human(app.humanBaseTr);
            
            % -- Create an arm object:
            app.arm = Arm(app.armBaseTr);

            % -- Turn on the switch button:
            app.TeachSwitch.Enable = "on";
            
            % -- Turn on the reset button:
            app.ResetEnvironmentButton.Enable = "on";
           
            % -- Turn on the slider:
            % Human Object Slider:
            app.HumanSlider.Enable = "on";
            jointLimit = app.human.model.qlim;
            app.HumanSlider.Limits = jointLimit(1,:);

            % Arm Object Slider:
            app.ArmSlider.Enable = "on";
            jointLimit = rad2deg(app.arm.model.qlim);
            app.ArmSlider.Limits = jointLimit(1,:);

            % -- Turn off the simulate button:
            app.LoadSimulateButton.Enable = "off";
            
            % -- Turn on the function button:
            app.RunMissionButton.Enable = "on";
            
            % -- Turn on the visual servoing button:
            app.VisualServoingButton.Enable = "on";

            % -- Update the Joint state + end-effector data:
            app.UpdateJointStateData();
            app.UpdateEndEffectorData();

            % -- Set up the initial Lamp:
            app.NormalizeLamp();
            
            % -- Change the main colour lamp
            app.Lamp_7.Color = [0 1 0];
            app.Lamp_8.Color = [0 1 0];
            
            % -- Inform to the user:
            app.TextArea.Value = sprintf("Ready to load program!");

        end

        % Value changed function: TeachSwitch
        function TeachSwitchValueChanged(app, event)
            if strcmp(app.TeachSwitch.Value, 'On')
                % -- Turn on the teach button:
                app.TeachTM5Button.Enable = "on";
                app.TeachTM12Button.Enable = "on";

                % -- Setup the light
                app.Lamp.Color = [0, 0.9, 0];
                app.Lamp_2.Color = [0, 0.9, 0];

                % -- Turn off the load program if teaching activate is on:
                app.RunMissionButton.Enable = "off";

                % -- Turn off the visual sevoing button if teaching activate is on:
                app.VisualServoingButton.Enable = "off";

            else
                % -- Turn off the teach button:
                app.TeachTM5Button.Enable = "off";
                app.TeachTM12Button.Enable = "off";
                
                % -- Setup the light
                app.Lamp.Color = [0.9, 0, 0];
                app.Lamp_2.Color = [0.9, 0, 0];

                % Close the TeachPendant app for the TM5:
                if ~isempty(app.TeachPendantAppHandle)
                    delete(app.TeachPendantAppHandle);
                    app.TeachPendantAppHandle = [];
                end

                % Close the TeachPendant app for the TM12:
                if ~isempty(app.TeachPendantTM12AppHandle)
                    delete(app.TeachPendantTM12AppHandle);
                    app.TeachPendantTM12AppHandle = [];
                end

                % -- Turn on the load program if teaching activate is off:
                app.RunMissionButton.Enable = "on";

                % -- Turn on the visual sevoing button if teaching activate is off:
                app.VisualServoingButton.Enable = "on";
            end
        end

        % Button pushed function: ResetEnvironmentButton
        function ResetEnvironmentButtonPushed(app, event)
            if ~isempty(figure(1))
                close(1)
            end
            % -- Turn off the teach Button:
            app.TeachSwitch.Enable = "off";
            app.TeachTM5Button.Enable = "off";
            app.TeachTM12Button.Enable = "off";

            % -- Turn on the simulate button:
            app.LoadSimulateButton.Enable = "on";

            % -- Turn off the reset button:
            app.ResetEnvironmentButton.Enable = "off";

            % -- Turn off the switch button:
            app.TeachSwitch.Enable = "off";
            
            % -- Turn off the slider:
            app.HumanSlider.Enable = "off";
            app.ArmSlider.Enable = "off";
            
            % -- Turn off the E-Stop button and switch if on:
            app.EStopButton.Enable = "off";
            app.EStopSwitch.Enable = "off";

            % Close the TeachPendant app for the TM5:
            if ~isempty(app.TeachPendantAppHandle)
                delete(app.TeachPendantAppHandle);
                app.TeachPendantAppHandle = [];
            end

            % Close the TeachPendant app for the TM12:
            if ~isempty(app.TeachPendantTM12AppHandle)
                delete(app.TeachPendantTM12AppHandle);
                app.TeachPendantTM12AppHandle = [];
            end
    
        end

        % Button pushed function: TeachTM12Button
        function TeachTM12ButtonPushed(app, event)
            app.TeachPendantTM12AppHandle = TeachPendantTM12(app, app.tm12Robot.model.getpos);
        end

        % Value changed function: HumanSlider
        function HumanSliderValueChanged(app, event)
            currentPose = app.human.model.getpos;
            currentPose(1) = app.HumanSlider.Value;
            app.human.model.animate(currentPose);
        end

        % Value changed function: ArmSlider
        function ArmSliderValueChanged(app, event)
            currentPose = app.arm.model.getpos;
            currentPose(1) = app.ArmSlider.Value;
            app.arm.model.animate(deg2rad(currentPose));
        end

        % Button pushed function: RunMissionButton
        function RunMissionButtonPushed(app, event)
            
            % -- Change the main colour lamp
            app.Lamp_7.Color = [0 0 1];
            app.Lamp_8.Color = [0 0 1];

            % -- Inform to the user:
            app.TextArea.Value = sprintf("Running Program!");

            % Disable the button:
            app.RunMissionButton.Enable = 'off';
            
            % -- Turn off the visual sevoing button if the system is running
            app.VisualServoingButton.Enable = "off";

            % Disable the reset environment:
            app.ResetEnvironmentButton.Enable = 'off';

            % Disable the teaching mod when load program:
            app.TeachSwitch.Enable = "off";

            % Enable the e-stop:
            app.EStopButton.Enable = "on";

            % Enable the Cancel button:
            app.CancelButton.Enable = "on";

            % Create an object mission:
            mission = Mission(app);
            
            %% --------- MAIN FUNCTION START FROM HERE ------------------ %%
            % mission.TestFillProduct(app.tm5Robot, app.products);
            % -- Step 1: TODO
            mission.MoveFirstProduct(app.tm5Robot, app.tm12Robot, app.human, app.arm, app.products{1});
            % 
            % -- Step 2: TODO
            mission.FirstMoveBackAndFirstPick(app.tm5Robot, app.tm12Robot, app.human, app.arm, app.products);
            % 
            % -- Step 3: TODO
            mission.PickLastProduct(app.tm5Robot, app.tm12Robot, app.human, app.arm, app.products{6});
            
            % -- Step 4: TODO
            mission.TM5FillProduct(app.tm5Robot, app.tm12Robot, app.human, app.arm, app.products);

            % -- Step 5: Bring it home
            mission.HomeTM5(app.tm5Robot, app.human, app.arm);
            % ------------ END OF MAIN FUNCTION --------------------------%
            
            % -- Homing two robots after finishing the mission:
            mission.HomingRobot(app.tm5Robot, app.tm12Robot);

            % -- Enable the reset environment after the program is finnished:
            app.ResetEnvironmentButton.Enable = 'on';
            
            % -- Turn off the cancel button when the mission is finished:
            app.CancelButton.Enable = "off";
            
            % -- Reset the cancel flag after misison completed:
            app.cancelFlag = false;

            % -- Turning the Run Mission Button Again:
            app.RunMissionButton.Enable = 'on';
            
            % -- Turn on the visual sevoing button if the mission finished
            app.VisualServoingButton.Enable = "on";

            % -- Change the main colour lamp
            app.Lamp_7.Color = [0 1 0];
            app.Lamp_8.Color = [0 1 0];

            % -- Inform to the user:
            app.TextArea.Value = sprintf("Ready to load program!");

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            
            % Turn off the resume button:
            app.ResumeButton.Enable = "off";

            % Change the state of the robot:
            app.tm5Robot.robotState = 'normal';
            app.tm12Robot.robotState = 'normal';

            % Turn off the switch:
            app.EStopSwitch.Enable = "off";
            
            % Enable the E-Stop Button
            app.EStopButton.Enable = 'on';

            % Normalize the Lamp System:
            app.NormalizeLamp();

            % -- Change the main colour lamp
            app.Lamp_7.Color = [0 0 1];
            app.Lamp_8.Color = [0 0 1];

            % -- Inform to the user:
            app.TextArea.Value = sprintf("Running Program!");
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            % Change the state of the two robot to stop:
            app.tm5Robot.robotState = 'stop';
            app.tm12Robot.robotState = 'stop';
            
            % Turn off the eStop button:
            app.EStopButton.Enable = "off";

            % Warning Signal:
            app.Lamp_4.Color = [1 0 0];
            app.EStop1EditField.Value = sprintf('EStop Triggered!');
        end

        % Value changed function: EStopSwitch
        function EStopSwitchValueChanged(app, event)
            value = app.EStopSwitch.Value;
            
            if(strcmp(value, 'On'))
                app.ResumeButton.Enable = "on";
            else
                app.ResumeButton.Enable = "off";
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Raise the flag if the button is pressed:
            app.cancelFlag = true;
        end

        % Button pushed function: VisualServoingButton
        function VisualServoingButtonPushed(app, event)
            % ---- Visual Servoing Code Start from here:
            
            % -- Change the main colour lamp
            app.Lamp_7.Color = [0 0 1];
            app.Lamp_8.Color = [0 0 1];

            % -- Inform to the user:
            app.TextArea.Value = sprintf("Visual Servoing Demo");

            % Disable the run mission button:
            app.RunMissionButton.Enable = 'off';
            
            % -- Turn off the visual sevoing button if the system is running
            app.VisualServoingButton.Enable = "off";

            % -- Disable the reset environment:
            app.ResetEnvironmentButton.Enable = 'off';

            % -- Disable the teaching mod when load program:
            app.TeachSwitch.Enable = "off";
            
            % -- Create an object mission:
            mission = Mission(app);

            % -- Visual Servoing here:
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnvironmentPanel = uipanel(app.UIFigure);
            app.EnvironmentPanel.Title = 'Environment';
            app.TeachTM5Button = uibutton(app.EnvironmentPanel, 'push');
            app.TeachTM5Button.ButtonPushedFcn = createCallbackFcn(app, @TeachTM5ButtonPushed, true);
            app.TeachTM5Button.Enable = 'off';
            app.TeachTM5Button.Text = 'Teach TM5';
            app.LoadSimulateButton = uibutton(app.EnvironmentPanel, 'push');
            app.LoadSimulateButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSimulateButtonPushed, true);
            app.LoadSimulateButton.Text = 'Load Simulate';
            app.TeachTM12Button = uibutton(app.EnvironmentPanel, 'push');
            app.TeachTM12Button.ButtonPushedFcn = createCallbackFcn(app, @TeachTM12ButtonPushed, true);
            app.TeachTM12Button.Enable = 'off';
            app.TeachTM12Button.Text = 'Teach TM12';
            app.TeachSwitchLabel = uilabel(app.EnvironmentPanel);
            app.TeachSwitchLabel.Enable = 'off';
            app.TeachSwitchLabel.Text = 'Teach';
            app.TeachSwitch = uiswitch(app.EnvironmentPanel, 'toggle');
            app.TeachSwitch.ValueChangedFcn = createCallbackFcn(app, @TeachSwitchValueChanged, true);
            app.TeachSwitch.Enable = 'off';
            app.Lamp = uilamp(app.EnvironmentPanel);
            app.Lamp_2 = uilamp(app.EnvironmentPanel);
            app.ResetEnvironmentButton = uibutton(app.EnvironmentPanel, 'push');
            app.ResetEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @ResetEnvironmentButtonPushed, true);
            app.ResetEnvironmentButton.Enable = 'off';
            app.ResetEnvironmentButton.Text = 'Reset Environment';
            app.ObjectCollisionPanel = uipanel(app.UIFigure);
            app.ObjectCollisionPanel.Title = 'Object Collision';
            app.HumanSliderLabel = uilabel(app.ObjectCollisionPanel);
            app.HumanSliderLabel.Text = 'Human';
            app.HumanSlider = uislider(app.ObjectCollisionPanel);
            app.HumanSlider.ValueChangedFcn = createCallbackFcn(app, @HumanSliderValueChanged, true);
            app.HumanSlider.Enable = 'off';
            app.ArmSliderLabel = uilabel(app.ObjectCollisionPanel);
            app.ArmSliderLabel.Text = 'Arm';
            app.ArmSlider = uislider(app.ObjectCollisionPanel);
            app.ArmSlider.ValueChangedFcn = createCallbackFcn(app, @ArmSliderValueChanged, true);
            app.ArmSlider.Enable = 'off';
            app.LoadProgramPanel = uipanel(app.UIFigure);
            app.LoadProgramPanel.Title = 'Load Program';
            app.RunMissionButton = uibutton(app.LoadProgramPanel, 'push');
            app.RunMissionButton.ButtonPushedFcn = createCallbackFcn(app, @RunMissionButtonPushed, true);
            app.RunMissionButton.Enable = 'off';
            app.RunMissionButton.Text = 'Run Mission';
            app.EStopButton = uibutton(app.LoadProgramPanel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Enable = 'off';
            app.EStopButton.Text = 'E-Stop';
            app.ResumeButton = uibutton(app.LoadProgramPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Enable = 'off';
            app.ResumeButton.Text = 'Resume';
            app.EStopSwitchLabel = uilabel(app.LoadProgramPanel);
            app.EStopSwitchLabel.Enable = 'off';
            app.EStopSwitchLabel.Text = 'E-Stop';
            app.EStopSwitch = uiswitch(app.LoadProgramPanel, 'toggle');
            app.EStopSwitch.ValueChangedFcn = createCallbackFcn(app, @EStopSwitchValueChanged, true);
            app.EStopSwitch.Enable = 'off';
            app.CancelButton = uibutton(app.LoadProgramPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.JointStateDataPanel = uipanel(app.UIFigure);
            app.JointStateDataPanel.Title = 'Joint State Data';
            app.BaseEditFieldLabel = uilabel(app.JointStateDataPanel);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.JointStateDataPanel, 'text');
            app.BaseEditField.Editable = 'off';
            app.ShoulderEditFieldLabel = uilabel(app.JointStateDataPanel);
            app.ShoulderEditFieldLabel.Text = 'Shoulder';
            app.ShoulderEditField = uieditfield(app.JointStateDataPanel, 'text');
            app.ShoulderEditField.Editable = 'off';
            app.ElbowEditFieldLabel = uilabel(app.JointStateDataPanel);
            app.ElbowEditFieldLabel.Text = 'Elbow';
            app.ElbowEditField = uieditfield(app.JointStateDataPanel, 'text');
            app.ElbowEditField.Editable = 'off';
            app.Wrist1EditFieldLabel = uilabel(app.JointStateDataPanel);
            app.Wrist1EditFieldLabel.Text = 'Wrist 1';
            app.Wrist1EditField = uieditfield(app.JointStateDataPanel, 'text');
            app.Wrist1EditField.Editable = 'off';
            app.Wrist2EditFieldLabel = uilabel(app.JointStateDataPanel);
            app.Wrist2EditFieldLabel.Text = 'Wrist 2';
            app.Wrist2EditField = uieditfield(app.JointStateDataPanel, 'text');
            app.Wrist2EditField.Editable = 'off';
            app.Wrist3EditFieldLabel = uilabel(app.JointStateDataPanel);
            app.Wrist3EditFieldLabel.Text = 'Wrist 3';
            app.Wrist3EditField = uieditfield(app.JointStateDataPanel, 'text');
            app.Wrist3EditField.Editable = 'off';
            app.BaseEditField_2Label = uilabel(app.JointStateDataPanel);
            app.BaseEditField_2Label.Text = 'Base';
            app.BaseEditField_2 = uieditfield(app.JointStateDataPanel, 'text');
            app.BaseEditField_2.Editable = 'off';
            app.ShoulderEditField_2Label = uilabel(app.JointStateDataPanel);
            app.ShoulderEditField_2Label.Text = 'Shoulder';
            app.ShoulderEditField_2 = uieditfield(app.JointStateDataPanel, 'text');
            app.ShoulderEditField_2.Editable = 'off';
            app.ElbowEditField_2Label = uilabel(app.JointStateDataPanel);
            app.ElbowEditField_2Label.Text = 'Elbow';
            app.ElbowEditField_2 = uieditfield(app.JointStateDataPanel, 'text');
            app.ElbowEditField_2.Editable = 'off';
            app.Wrist1EditField_2Label = uilabel(app.JointStateDataPanel);
            app.Wrist1EditField_2Label.Text = 'Wrist 1';
            app.Wrist1EditField_2 = uieditfield(app.JointStateDataPanel, 'text');
            app.Wrist1EditField_2.Editable = 'off';
            app.Wrist2EditField_2Label = uilabel(app.JointStateDataPanel);
            app.Wrist2EditField_2Label.Text = 'Wrist 2';
            app.Wrist2EditField_2 = uieditfield(app.JointStateDataPanel, 'text');
            app.Wrist2EditField_2.Editable = 'off';
            app.Wrist3EditField_2Label = uilabel(app.JointStateDataPanel);
            app.Wrist3EditField_2Label.Text = 'Wrist 3';
            app.Wrist3EditField_2 = uieditfield(app.JointStateDataPanel, 'text');
            app.Wrist3EditField_2.Editable = 'off';
            app.TM5Label = uilabel(app.JointStateDataPanel);
            app.TM5Label.Text = 'TM5';
            app.TM12Label = uilabel(app.JointStateDataPanel);
            app.TM12Label.Text = 'TM12';
            app.EndEffectorDataPanel = uipanel(app.UIFigure);
            app.EndEffectorDataPanel.Title = 'End-Effector Data';
            app.TM5Label_2 = uilabel(app.EndEffectorDataPanel);
            app.TM5Label_2.Text = 'TM5';
            app.TM12Label_2 = uilabel(app.EndEffectorDataPanel);
            app.TM12Label_2.Text = 'TM12';
            app.PEditFieldLabel = uilabel(app.EndEffectorDataPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.EndEffectorDataPanel, 'text');
            app.PEditField.Editable = 'off';
            app.BaseEditField_3Label_2 = uilabel(app.EndEffectorDataPanel);
            app.BaseEditField_3Label_2.Text = 'O';
            app.BaseEditField_4 = uieditfield(app.EndEffectorDataPanel, 'text');
            app.BaseEditField_4.Editable = 'off';
            app.PEditFieldLabel_2 = uilabel(app.EndEffectorDataPanel);
            app.PEditFieldLabel_2.Text = 'P';
            app.PEditField_2 = uieditfield(app.EndEffectorDataPanel, 'text');
            app.PEditField_2.Editable = 'off';
            app.BaseEditField_3Label_3 = uilabel(app.EndEffectorDataPanel);
            app.BaseEditField_3Label_3.Text = 'O';
            app.BaseEditField_5 = uieditfield(app.EndEffectorDataPanel, 'text');
            app.BaseEditField_5.Editable = 'off';
            app.VisualServoingButton = uibutton(app.EndEffectorDataPanel, 'push');
            app.VisualServoingButton.ButtonPushedFcn = createCallbackFcn(app, @VisualServoingButtonPushed, true);
            app.VisualServoingButton.Enable = 'off';
            app.VisualServoingButton.Text = 'Visual Servoing';
            app.SensorDataPanel = uipanel(app.UIFigure);
            app.SensorDataPanel.Title = 'Sensor Data';
            app.Lamp_3 = uilamp(app.SensorDataPanel);
            app.Lamp_4 = uilamp(app.SensorDataPanel);
            app.LightCurtainEditFieldLabel = uilabel(app.SensorDataPanel);
            app.LightCurtainEditFieldLabel.Text = 'Light Curtain';
            app.LightCurtainEditField = uieditfield(app.SensorDataPanel, 'text');
            app.LightCurtainEditField.Editable = 'off';
            app.EStop1EditFieldLabel = uilabel(app.SensorDataPanel);
            app.EStop1EditFieldLabel.Text = 'E-Stop 1';
            app.EStop1EditField = uieditfield(app.SensorDataPanel, 'text');
            app.EStop1EditField.Editable = 'off';
            app.UltraSonicEditFieldLabel = uilabel(app.SensorDataPanel);
            app.UltraSonicEditFieldLabel.Text = 'UltraSonic';
            app.UltraSonicEditField = uieditfield(app.SensorDataPanel, 'text');
            app.UltraSonicEditField.Editable = 'off';
            app.Lamp_5 = uilamp(app.SensorDataPanel);
            app.Lamp_6 = uilamp(app.SensorDataPanel);
            app.EStop2EditFieldLabel = uilabel(app.SensorDataPanel);
            app.EStop2EditFieldLabel.Text = 'E-Stop 2';
            app.EStop2EditField = uieditfield(app.SensorDataPanel, 'text');
            app.EStop2EditField.Editable = 'off';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Gui Background', 'UTS-Logo-Syd.jpg');
            app.Lamp_7 = uilamp(app.UIFigure);
            app.Lamp_8 = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Gui Background', 'Omron_Automation_Americas_Logo.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end