classdef TeachPendant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        TM5Tab                matlab.ui.container.Tab
        EStopButton           matlab.ui.control.Button
        EditField_2           matlab.ui.control.EditField
        TCPSwitch             matlab.ui.control.ToggleSwitch
        TCPSwitchLabel        matlab.ui.control.Label
        PlanButton            matlab.ui.control.Button
        JSSwitch              matlab.ui.control.ToggleSwitch
        JSSwitchLabel         matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        Lamp                  matlab.ui.control.Lamp
        StatusLabel           matlab.ui.control.Label
        HomingButton          matlab.ui.control.Button
        CatesianControlPanel  matlab.ui.container.Panel
        InputZEditField       matlab.ui.control.NumericEditField
        InputZEditFieldLabel  matlab.ui.control.Label
        InputYEditField       matlab.ui.control.NumericEditField
        InputYLabel           matlab.ui.control.Label
        InputXEditField       matlab.ui.control.NumericEditField
        InputXEditFieldLabel  matlab.ui.control.Label
        WzButton_2            matlab.ui.control.Button
        WyButton_2            matlab.ui.control.Button
        WzButton              matlab.ui.control.Button
        WxButton              matlab.ui.control.Button
        WxButton_2            matlab.ui.control.Button
        WyButton              matlab.ui.control.Button
        VzButton              matlab.ui.control.Button
        VzButton_2            matlab.ui.control.Button
        VyButton_2            matlab.ui.control.Button
        VyButton              matlab.ui.control.Button
        VxButton_2            matlab.ui.control.Button
        VxButton              matlab.ui.control.Button
        TCPOrientationPanel   matlab.ui.container.Panel
        YButton_3             matlab.ui.control.Button
        PButton_2             matlab.ui.control.Button
        YButton_4             matlab.ui.control.Button
        RButton_2             matlab.ui.control.Button
        PButton               matlab.ui.control.Button
        RButton               matlab.ui.control.Button
        STARTButton           matlab.ui.control.Button
        Switch                matlab.ui.control.Switch
        SwitchLabel           matlab.ui.control.Label
        JointPositionPanel    matlab.ui.container.Panel
        Wrist3Slider          matlab.ui.control.Slider
        Wrist3SliderLabel     matlab.ui.control.Label
        Wrist2Slider          matlab.ui.control.Slider
        Wrist2SliderLabel     matlab.ui.control.Label
        Wrist1Slider          matlab.ui.control.Slider
        Wrist1SliderLabel     matlab.ui.control.Label
        ElbowSlider           matlab.ui.control.Slider
        ElbowSliderLabel      matlab.ui.control.Label
        ShoulderSlider        matlab.ui.control.Slider
        ShoulderSliderLabel   matlab.ui.control.Label
        BaseSlider            matlab.ui.control.Slider
        BaseSliderLabel       matlab.ui.control.Label
        Button_12             matlab.ui.control.Button
        Button_11             matlab.ui.control.Button
        Button_10             matlab.ui.control.Button
        Button_9              matlab.ui.control.Button
        Button_8              matlab.ui.control.Button
        Button_7              matlab.ui.control.Button
        Button_6              matlab.ui.control.Button
        Button_5              matlab.ui.control.Button
        Button_4              matlab.ui.control.Button
        Button_3              matlab.ui.control.Button
        Button_2              matlab.ui.control.Button
        Button_1              matlab.ui.control.Button
        Wrist3Display         matlab.ui.control.EditField
        Wrist2Display         matlab.ui.control.EditField
        Wrist1Display         matlab.ui.control.EditField
        ElbowDisplay          matlab.ui.control.EditField
        ShoulderDisplay       matlab.ui.control.EditField
        BaseDisplay           matlab.ui.control.EditField
        ToolPositionPanel     matlab.ui.container.Panel
        YawEditField          matlab.ui.control.EditField
        YawEditFieldLabel     matlab.ui.control.Label
        PitchEditField        matlab.ui.control.EditField
        PitchEditFieldLabel   matlab.ui.control.Label
        RollEditField         matlab.ui.control.EditField
        RollEditFieldLabel    matlab.ui.control.Label
        ZEditField_2          matlab.ui.control.EditField
        ZEditField_2Label     matlab.ui.control.Label
        YEditField_2          matlab.ui.control.EditField
        YEditField_2Label     matlab.ui.control.Label
        XEditField_2          matlab.ui.control.EditField
        XEditField_2Label     matlab.ui.control.Label
        TCPPositionPanel      matlab.ui.container.Panel
        YButton_2             matlab.ui.control.Button
        ZButton               matlab.ui.control.Button
        ZButton_2             matlab.ui.control.Button
        XButton_2             matlab.ui.control.Button
        YButton               matlab.ui.control.Button
        XButton               matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end


    % ------------- Private Property of this GUI -------------------------%
    properties (Access = public)

        % ---- 1. Create robot models below:
        % Create a robot model for teaching tasks:
        simulateRobot;
        qHome = [];

        % ---- 2. Other Data member:
        robotOption;            %<! The robot option
        handle;                 %<! Find the handle of the robot in the app
        joy;                    %<! a joy object to control the robot:
        MainAppHandle = [];     %<! Handle to the Main Gui app;
        eStopFlag = false;      %<! E-Stop flag in the teach pendant
    end

    methods (Access = private)

        %% Update the End Effector Pose:
        % This function updates the display of the end effector's pose for the TM5 robot in the teaching mode.
        function UpdateEndEffectorDisplay(app)

            % --- Get the forward kinematic of the robot the get the pose of the end-effector:
            T = app.MainAppHandle.tm5Robot.model.fkine(app.MainAppHandle.tm5Robot.model.getpos).T;

            % --- Get the current position and orientation:
            position = T(1:3, 4)';
            orientation = tr2rpy(T);

            % --- Update Position Edit Fields
            app.XEditField_2.Value = num2str(position(1));
            app.YEditField_2.Value = num2str(position(2));
            app.ZEditField_2.Value = num2str(position(3));

            % --- Update Orientation Edit Fields
            app.RollEditField.Value = num2str(orientation(1));
            app.PitchEditField.Value = num2str(orientation(2));
            app.YawEditField.Value = num2str(orientation(3));

        end

        %% Update the Slider Value from the currentQ:
        function UpdateSliderValue(app)
            try
                app.BaseSlider.Value = rad2deg(app.qHome(1));
                app.ShoulderSlider.Value = rad2deg(app.qHome(2));
                app.ElbowSlider.Value = rad2deg(app.qHome(3));
                app.Wrist1Slider.Value = rad2deg(app.qHome(4));
                app.Wrist2Slider.Value = rad2deg(app.qHome(5));
                app.Wrist3Slider.Value = rad2deg(app.qHome(6));
            catch
                disp('Warning Label Limit!!');
            end
        end

        %% Update the Slider Value Display (Used for TM5 Teach)
        function UpdateSliderValueDisplay(app)
            % --- Get the value of the slider
            app.BaseDisplay.Value = num2str(rad2deg(app.qHome(1)));
            app.ShoulderDisplay.Value = num2str(rad2deg(app.qHome(2)));
            app.ElbowDisplay.Value = num2str(rad2deg(app.qHome(3)));
            app.Wrist1Display.Value = num2str(rad2deg(app.qHome(4)));
            app.Wrist2Display.Value = num2str(rad2deg(app.qHome(5)));
            app.Wrist3Display.Value = num2str(rad2deg(app.qHome(6)));
        end

        %% Find Handle in the GUI:
        function FindHandleTM5(app)
            app.handle = [];
            for childIndex = 1:length(app.UIAxes.Children)
                if strcmp(app.UIAxes.Children(childIndex).Tag,app.simulateRobot.model.name)
                    app.handle = app.UIAxes.Children(childIndex);
                end
            end
        end

        %% Update the robot when the slider change:
        function ChangeSlider(app, value, index)

            % --- Reset the Warning when begin the function:
            app.EditField_2.Value = sprintf('');

            % --- Change the Lamp colour to green:
            app.Lamp.Color = [0 1 0];

            % Condition Variable:
            passCondtion = true;

            % From the input value:
            qCurrent = app.qHome;
            qCurrent(index) = deg2rad(value);

            % -- Consider the joint limit:
            for j = 1:app.simulateRobot.model.n
                if qCurrent(j) < app.simulateRobot.model.qlim(j,1)
                    passCondtion = false; % Stop the motor
                    app.Lamp.Color = [1 0 0];
                    app.EditField_2.Value = sprintf('Joint Limit!!');
                elseif qCurrent(j) > app.simulateRobot.model.qlim(j,2)
                    passCondtion = false; % Stop the motor
                    app.Lamp.Color = [1 0 0];
                    app.EditField_2.Value = sprintf('Joint Limit!!');
                end
            end

            % - Check Ground-Collision:
            checkGroundCollision = app.simulateRobot.GroundCollision(qCurrent);

            % - Check Self-collision
            checkSelfCollision = app.MainAppHandle.tm5Robot.CheckSelfCollision();

            % - Consider the ground collision and self-collsition
            if checkSelfCollision == true || checkGroundCollision == true
                passCondtion = false;

                % Warning for the user:
                app.EditField_2.Value = sprintf('Collision Detection!');

                % Change the Lamp colour to warning:
                app.Lamp.Color = [1 0 0];
            end

            % -- Animate the q:
            if passCondtion == true
                % --- Update the qHome based on the slider value
                app.qHome(index) = deg2rad(value);

                % --- Display the value:
                app.UpdateSliderValueDisplay;

                % --- Find handle and Animate the currentQ
                app.FindHandleTM5();
                app.AnimateModfiled(app.simulateRobot, app.qHome, app.handle);

                % --- Update the information for the main gui:
                app.MainAppHandle.tm12Robot.model.animate(app.qHome);
                app.MainAppHandle.UpdateJointStateData();
                app.MainAppHandle.UpdateEndEffectorData();
                drawnow;

                % --- Update the value of the end effector data:
                app.UpdateEndEffectorDisplay;
            end
        end

        %% Animate Modfiled (Used for all model)
        function AnimateModfiled(app, robot, qq, handles)
            if nargin < 3
                handles = findobj('Tag', robot.name);
            end

            robot = robot.model;

            links = robot.links;
            N = robot.n;

            % MAIN DISPLAY/ANIMATION LOOP
            while true
                % animate over all instances of this robot in different axes

                for q=qq'  % for all configurations in trajectory
                    q = q';
                    for handle=handles'
                        h = get(handle, 'UserData');

                        debug = false;
                        if debug
                            disp('ROBOT LINKS')
                            disp(links)
                            disp('H')
                            disp(h)
                            disp('H.WRIST')
                            disp(h.wrist)
                            disp('H.WRIST.CHILDREN')
                            disp(h.wrist.Children)
                            disp('H.LINK')
                            disp(h.link)
                        end

                        % now draw it for a pose q
                        if robot.mdh
                            % modified DH case
                            T = robot.base;
                            vert = T.t';

                            for L=1:N
                                if robot.links(L).isprismatic()
                                    % scale the box representing the prismatic joint
                                    % it is based at the origin and extends in z-direction
                                    if q(L) > 0
                                        set(h.pjoint(L), 'Matrix', diag([1 1 q(L) 1]));
                                    else
                                        % if length is zero the matrix is singular and MATLAB complains
                                        %error('Prismatic length must be > 0');
                                    end
                                end
                                T = T * links(L).A(q(L));
                                set(h.link(L), 'Matrix', T.T);
                                vert = [vert; T.t'];
                            end
                            % update the transform for link N+1 (the tool)
                            T = T * robot.tool;
                            if length(h.link) > N
                                set(h.link(N+1), 'Matrix', T.T);
                            end
                            vert = [vert; T.t'];
                        else
                            % standard DH case
                            T = robot.base;
                            vert = T.t';

                            for L=1:N
                                % for all N links

                                if robot.links(L).isprismatic()
                                    %disp(['q(L) = ', num2str(q(L))])
                                    % scale the box representing the prismatic joint
                                    % it is based at the origin and extends in z-direction

                                    % Looking at the way this pjoint issue was
                                    % solved in the V9 code, the error was simply
                                    % commented out.
                                    scicEdit = true;

                                    if ~scicEdit
                                        assert( q(L) >= 0, 'Prismatic joint length must be >= 0'); % link lengths must be positive

                                        % if scale factor is zero the matrix is singular and MATLAB complains
                                        %  so we make it no smaller than eps

                                        set(h.pjoint(L), 'Matrix', diag([1 1 max(eps, q(L)) 1]));
                                    else
                                        if q(L) > 0
                                            set(h.pjoint(L), 'Matrix', diag([1 1 q(L) 1]));
                                        else
                                            % if length is zero the matrix is singular and MATLAB complains
                                            %error('Prismatic length must be > 0');
                                        end
                                    end

                                end

                                % now set the transform for frame {L}, this controls the displayed pose of:
                                %   the pipes associated with link L, that join {L} back to {L-1}
                                %   (optional) a prismatic joint L, that joins {L} to {L+1}
                                if h.link(L) ~= 0
                                    % for plot3d, skip any 0 in the handle list
                                    set(h.link(L), 'Matrix', T.T);
                                end

                                T = T * links(L).A(q(L));
                                vert = [vert; T.t'];
                            end
                            % update the transform for link N+1 (the tool)
                            T = T*robot.tool;
                            if length(h.link) > N
                                set(h.link(N+1), 'Matrix', T.T);
                            end

                            vert = [vert; T.t'];
                        end

                        % now draw the shadow
                        if isfield(h, 'shadow')
                            set(h.shadow, 'Xdata', vert(:,1), 'Ydata', vert(:,2), ...
                                'Zdata', h.floorlevel*ones(size(vert(:,1))));
                        end

                        % update the tool tip trail
                        if isfield(h, 'trail')
                            T = robot.fkine(q);
                            robot.trail = [robot.trail; transl(T)];
                            set(h.trail, 'Xdata', robot.trail(:,1), 'Ydata', robot.trail(:,2), 'Zdata', robot.trail(:,3));
                        end

                        % animate the wrist frame
                        if ~isempty(h.wrist)
                            trplot(T, 'handle', h.wrist);
                        end

                        % add a frame to the movie
                        if ~isempty(h.robot.movie)
                            h.robot.movie.add();
                        end

                        if h.robot.delay > 0
                            pause(h.robot.delay);
                            drawnow
                        end
                        h.q = q;
                        set(handle, 'UserData', h);
                    end
                end

                try
                    if ~h.robot.loop
                        break;
                    end
                catch
                    warning(['in animate(). It is likely that there is no robot plot handle named: ''',robot.name,''' in any open figures. Has it been deleted?'])
                    break;
                end
            end

        end

        %% ControlByEndEffectorPosition:
        function ControlByEndEffectorPosition(app, point1, point2, delay, numSteps)
            
            % --- Reset the Warning when begin the function:
            app.EditField_2.Value = sprintf('');

            % --- Change the Lamp colour to green:
            app.Lamp.Color = [0 1 0];

            % --- Check the delay and steps flag:
            timeDelay = 0;
            steps = 2;

            if nargin > 3
                timeDelay = delay;
            end
            
            if nargin > 4
                steps = numSteps;
            end

            % --- Initial parameter
            t = 0.1;                                % Total Time of motion
            deltaT = t/steps;                       % Small Angle Change
            threshold = 0.045;                      % Threshhold value for manipulability
            waypoints = zeros(steps,3);             % Waypoints matrix

            % --- Linear interpolation between the two points
            for i = 1:steps
                t = (i - 1) / (steps - 1);  % Interpolation parameter [0, 1]
                waypoints(i, :) = (1 - t) * point1 + t * point2;
            end

            % --- Create a matrix of joint angles
            qMatrix = nan(steps,app.simulateRobot.model.n);

            % --- Get the initial Joint Angles:
            qMatrix(1, :) = app.qHome;

            % --- Track the trajectory with RMRC:
            for i = 1:steps-1
                % - Get forward transformation at current joint state:
                T = app.simulateRobot.model.fkine(qMatrix(i,:)).T;

                % - Get Position Error from next waypoint:
                deltaX = waypoints(i+1, :) - T(1:3,4)';

                % - Get the linear velocity:
                linearVelocity = (1/deltaT)*deltaX;

                % - Get the angular velocity:
                angularVelocity = [0,0,0];

                % - Calculate the end-effector velocity to reach next waypoint:
                xDot = [linearVelocity'; angularVelocity'];

                % - Find the Jacobian Matrix at the current pose:
                J = app.simulateRobot.model.jacob0(qMatrix(i,:));

                % -- Get the inverse of the Jacobean:
                
                % - Measure of Manipulability
                mu = sqrt(det(J*J'));

                % - Setup lamda_max:
                lambda_max = 0.4;

                % - Setup damping factor:
                lambda = 0;

                % - Setup the condition to apply the damping factor:
                if mu < threshold
                    lambda = (1- (mu/threshold)^2)*lambda_max;
                    if mu < 0.02
                        disp('[Warning]: The Robot is very near Singularity! Go Back!');
                    end
                end
                
                % - Determine the inverse of Jacobean:
                invJ = J'*inv(J*J'+lambda*eye(6));
                
                % - Solve velocities via RMRC:
                qdot = invJ*xDot;

                % - Consider the joint limit:
                for j = 1:app.simulateRobot.model.n
                    if qMatrix(i,j) + deltaT*qdot < app.simulateRobot.model.qlim(j,1)
                        qdot = 0; % Stop the motor
                        app.Lamp.Color = [1 0 0];
                        app.EditField_2.Value = sprintf('Joint Limit!!');
                    elseif qMatrix(i,j) + deltaT*qdot > app.simulateRobot.model.qlim(j,2)
                        qdot = 0; % Stop the motor
                        app.Lamp.Color = [1 0 0];
                        app.EditField_2.Value = sprintf('Joint Limit!!');
                    end
                end
                
                % - Check the ground collision
                checkGroundCollision = app.simulateRobot.GroundCollision(qMatrix(i,:) + deltaT*qdot');

                % - Check Self-collision
                checkSelfCollision = app.MainAppHandle.tm5Robot.CheckSelfCollision();

                % - Consider the ground collision and self-collsition
                if checkGroundCollision == true || checkSelfCollision == true
                    qdot = 0;

                    % Warning to the user:
                    if checkGroundCollision == true
                        app.EditField_2.Value = sprintf('Ground-Collision!!');
                    elseif checkSelfCollision == true
                        app.EditField_2.Value = sprintf('Self-Collision!!');
                    end

                    % Change the Lamp colour to warning:
                    app.Lamp.Color = [1 0 0];
                end
                
                % - Check the E-Stop button:
                if app.eStopFlag == true
                    % When e-stop is pressed, update the current position:
                    app.qHome = qMatrix(i,:);

                    % Update data:
                    app.UpdateEndEffectorDisplay;
                    app.UpdateSliderValueDisplay;
                    app.UpdateSliderValue;

                    % Update Data to main gui:
                    app.MainAppHandle.UpdateJointStateData();
                    app.MainAppHandle.UpdateEndEffectorData();

                    % Update Warning to user:
                    app.Lamp.Color = [1 0 0];
                    app.EditField_2.Value = sprintf('E-Stop Pressed!');
                    break;
                end

                % - Update next joint state based on joint velocities:
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qdot';

                % - Store the joint state to the data member of this class:
                app.qHome = qMatrix(i+1,:);

                % - Animate the path:
                app.FindHandleTM5();
                app.AnimateModfiled(app.simulateRobot, qMatrix(i+1,:), app.handle);

                % - Update information to the main gui
                app.MainAppHandle.tm5Robot.model.animate(app.qHome);
                app.MainAppHandle.UpdateJointStateData();
                app.MainAppHandle.UpdateEndEffectorData();

                % - Update the slider:
                app.BaseSlider.Value = rad2deg(app.qHome(1));
                app.ShoulderSlider.Value = rad2deg(app.qHome(2));
                app.ElbowSlider.Value = rad2deg(app.qHome(3));
                app.Wrist1Slider.Value = rad2deg(app.qHome(4));
                app.Wrist2Slider.Value = rad2deg(app.qHome(5));
                app.Wrist3Slider.Value = rad2deg(app.qHome(6));

                % - Update Label:
                app.UpdateEndEffectorDisplay;
                app.UpdateSliderValue;
                app.UpdateSliderValueDisplay;

                % - Pause a little bit:
                pause(timeDelay);

            end

            % Reset the E-Stop Flag after breaking the for loop
            app.eStopFlag = false;
        end

        %% ControlByEndEffectorOrientation:
        function ControlByEndEffectorOrientation(app, startOrientation, endOrientation)
            
            % --- Reset the Warning when begin the function:
            app.EditField_2.Value = sprintf('');

            % --- Change the Lamp colour to green:
            app.Lamp.Color = [0 1 0];

            % --- Initial parameter
            t = 0.1;                              % Total Time of motion
            steps = 2;                            % Number of steps
            deltaT = t/steps;                     % Small Angle Change
            threshold = 0.2;                      % Threshhold value for manipulability

            % --- Create a matrix of joint angles
            qMatrix = nan(steps, app.simulateRobot.model.n);
            qMatrix(1, :) = app.qHome;

            % --- Interpolate between start and end orientations
            % Using SLERP (spherical linear interpolation)
            quatStart = rotm2quat(startOrientation);
            quatEnd = rotm2quat(endOrientation);

            quatInterpolated = zeros(steps, 4); % Quaternion waypoints
            for i = 1:steps
                s = (i - 1) / (steps - 1);
                quatInterpolated(i, :) = quatinterp(quatStart, quatEnd, s, 'slerp');
            end

            % --- Track the trajectory with RMRC
            for i = 1:steps - 1
                
                % -- Current pose and orientation
                currentPose = app.simulateRobot.model.fkine(qMatrix(i,:)).T;
                currentOre = currentPose(1:3,1:3);

                % -- Desired orientation from the interpolated quaternion
                desiredOre = quat2rotm(quatInterpolated(i+1,:));

                % -- Rotation matrix from current to desired orientation
                rotationMatrix = desiredOre*currentOre';

                % -- Convert the rotation matrix to a rotation vector (axis-angle representation)
                angle = rotm2axang(rotationMatrix);

                % -- Convert the rotation vector to a 3x1 angular velocity vector
                omega = angle(:,1:3)'*angle(:,4) / deltaT;

                % -- In this case, we're only focusing on orientation, so linear velocity is zero
                xdot = [0; 0; 0];

                % -- Combine velocities
                combinedVelocity = [xdot; omega];

                % -- Get the Jacobean value:
                J = app.simulateRobot.model.jacob0(qMatrix(i,:));

                % -- Measure of Manipulability
                mu = sqrt(det(J*J'));

                if mu < threshold
                    lambda = 0.01;
                    invJ = J'*inv(J*J'+lambda*eye(6));
                else
                    lambda = 0;
                    invJ = J'*inv(J*J'+lambda*eye(6));
                end

                % -- Solve velocities via RMRC:
                qdot = invJ*combinedVelocity;
                
                % -- Consider the joint limit:
                for j = 1:app.simulateRobot.model.n
                    if qMatrix(i,j) + deltaT*qdot < app.simulateRobot.model.qlim(j,1)
                        qdot = 0; % Stop the motor
                        app.Lamp.Color = [1 0 0];
                        app.EditField_2.Value = sprintf('Joint Limit!!');
                    elseif qMatrix(i,j) + deltaT*qdot > app.simulateRobot.model.qlim(j,2)
                        qdot = 0; % Stop the motor
                        app.Lamp.Color = [1 0 0];
                        app.EditField_2.Value = sprintf('Joint Limit!!');
                    end
                end
                
                % - Check the ground collision
                checkGroundCollision = app.simulateRobot.GroundCollision(qMatrix(i,:) + deltaT*qdot');

                % - Check Self-collision
                checkSelfCollision = app.MainAppHandle.tm5Robot.CheckSelfCollision();

                % - Consider the ground collision and self-collsition
                if checkGroundCollision == true || checkSelfCollision == true
                    qdot = 0;

                    % Warning to the user:
                    if checkGroundCollision == true
                        app.EditField_2.Value = sprintf('Ground-Collision!!');
                    elseif checkSelfCollision == true
                        app.EditField_2.Value = sprintf('Self-Collision!!');
                    end

                    % Change the Lamp colour to warning:
                    app.Lamp.Color = [1 0 0];
                end
                
                % -- Check the E-Stop button:
                if app.eStopFlag == true
                    % Break the while loop when the e-stop is pressed:
                    break;
                end

                % -- Update next joint state based on joint velocities:
                qMatrix(i+1,:) = qMatrix(i,:) + deltaT*qdot';

                % -- Update to the data member of the class:
                app.qHome = qMatrix(i+1,:);

                % -- Animate the path:
                app.FindHandleTM5();
                app.AnimateModfiled(app.simulateRobot, qMatrix(i+1,:), app.handle);

                % -- Update information to the main gui:
                app.MainAppHandle.tm5Robot.model.animate(app.qHome);
                app.MainAppHandle.UpdateJointStateData();
                app.MainAppHandle.UpdateEndEffectorData();

                % -- Update the slider:
                app.BaseSlider.Value = rad2deg(app.qHome(1));
                app.ShoulderSlider.Value = rad2deg(app.qHome(2));
                app.ElbowSlider.Value = rad2deg(app.qHome(3));
                app.Wrist1Slider.Value = rad2deg(app.qHome(4));
                app.Wrist2Slider.Value = rad2deg(app.qHome(5));
                app.Wrist3Slider.Value = rad2deg(app.qHome(6));

                % -- Update Label:
                app.UpdateEndEffectorDisplay;
                app.UpdateSliderValueDisplay;
            end

        end

        %% ControlByJoyStick:
        function ControlByJoyStick(app)

            % Create and connect to Joy Stick:
            % --- Create a joy to control the robot:
            try
                app.joy = vrjoystick(1);
            catch ME
                warning('Failed to create joystick object. Exiting function.');
                return;
            end

            % Set up the initial parameters:
            duration = 300;
            dt = 0.2;

            % --- Get the current q:
            currentQ = app.qHome;

            while(strcmp(app.Switch.Value, 'On'))

                % ------ I. Setup the value for the joint:
                % --- Read the value from the joint stick:
                try
                    [axes, buttons, povs] = read(app.joy);
                catch ME  % Handle the error
                    warning('Joystick not connected. Exiting function.');
                    return;
                end
                
                % Normalize the warning site:
                app.EditField_2.Value = sprintf('Safe');

                % Normialize the Lamp
                app.Lamp.Color = [0 0 1];

                % --- Set up the gain value for controlling velocity:
                Kv = 0.1; % linear velocity gain
                Kw = 0.1; % angular velocity gain

                % --- Set up linear velocity:
                vx = Kv*axes(1);
                vy = Kv*axes(2);
                vz = Kv*(buttons(4)-buttons(1));

                % --- Set up angular velocity:
                wx = Kw*axes(3);
                wy = Kw*axes(4);
                wz = Kw*(buttons(3)-buttons(2));

                % --- Combine the velocity vector from two velocity:
                dx = [vx;vy;vz;wx;wy;wz];

                % ------ II. Send the velocity to the robot:

                % --- Set up the threshhold
                threshold = 0.045;

                % --- Get the Jacobean of the current q:
                J = app.simulateRobot.model.jacob0(currentQ);

                % --- Get the inverse of the Jacobean:
                % Measure of Manipulability
                mu = sqrt(det(J*J'));
                
                % Setup lamda_max:
                lambda_max = 0.4;
                
                % Setup damping factor:
                lambda = 0;
                
                % Setup the condition to apply the damping factor:
                if mu < threshold
                    lambda = (1- (mu/threshold)^2)*lambda_max;
                    if mu < 0.032
                        disp('[Warning]: The Robot is very near Singularity! Go Back!');
                    end
                end
                
                % Determine the inverse of Jacobean:
                invJ = J'*inv(J*J'+lambda*eye(6));
                
                % Determine the qDot:
                qdot = invJ*dx;
                
                % - Check the ground collision
                checkGroundCollision = app.simulateRobot.GroundCollision(currentQ + dt*qdot');

                % - Check Self-collision
                checkSelfCollision = app.MainAppHandle.tm5Robot.CheckSelfCollision();

                % - Consider the ground collision and self-collsition
                if checkGroundCollision == true || checkSelfCollision == true
                    qdot = 0;
                    % Warning to the user:
                    if checkGroundCollision == true
                        app.EditField_2.Value = sprintf('Collision!!');
                    elseif checkSelfCollision == true
                        app.EditField_2.Value = sprintf('Collision!!');
                    end

                    % Change the Lamp colour to warning:
                    app.Lamp.Color = [1 0 0];
                end

                % Find the next q from the qdot:
                currentQ = currentQ + dt*qdot';
                
                % Update to the data member:
                app.qHome = currentQ;
            
                % Animate the q:
                app.FindHandleTM5()
                app.AnimateModfiled(app.simulateRobot, app.qHome, app.handle);

                % Update information to the main gui:
                app.MainAppHandle.tm5Robot.model.animate(app.qHome);
                app.MainAppHandle.UpdateJointStateData();
                app.MainAppHandle.UpdateEndEffectorData();

                % Update to the Slider Value:
                app.UpdateEndEffectorDisplay;
                app.UpdateSliderValueDisplay();

                drawnow;

            end

        end

        %% ControlByCatersian
        function ControlByCatersian(app, vx, vy, vz, wx, wy, wz)
            % --- Reset the Warning when begin the function:
            app.EditField_2.Value = sprintf('');

            % --- Change the Lamp colour to green:
            app.Lamp.Color = [0 1 0];

            % Set up initial parameters:
            dt = 0.1;
            
            % Get the current q:
            currentQ = app.qHome;

            % --- Combine the velocity vector from two velocity:
            dx = [vx;vy;vz;wx;wy;wz];

            % ------ II. Send the velocity to the robot:
            % --- Set up the threshhold
            threshold = 0.045;

            % --- Get the Jacobean of the current q:
            J = app.simulateRobot.model.jacob0(currentQ);

            % --- Get the inverse of the Jacobean:
            % Measure of Manipulability
            mu = sqrt(det(J*J'));

            % Setup lamda_max:
            lambda_max = 0.4;

            % Setup damping factor:
            lambda = 0;

            % Setup the condition to apply the damping factor:
            if mu < threshold
                lambda = (1- (mu/threshold)^2)*lambda_max;
                if mu < 0.02
                    disp('[Warning]: The Robot is very near Singularity! Go Back!');
                end
            end

            % Determine the inverse of Jacobean:
            invJ = J'*inv(J*J'+lambda*eye(6));

            % Get the velocity of the joint:
            qdot = invJ*dx;
            
            % -- Consider the ground collision
            if app.simulateRobot.GroundCollision(currentQ + dt*qdot') == true
                qdot = 0;

                % Warning to the user:
                app.EditField_2.Value = sprintf('Collision!!');

                % Change the Lamp colour to warning:
                app.Lamp.Color = [1 0 0];
            end

            % Find the next q from the qdot:
            currentQ = currentQ + dt*qdot';
            
            % Check Ground Collision:

            % Update to the data member:
            app.qHome = currentQ;

            % Animate the q:
            app.FindHandleTM5()
            app.AnimateModfiled(app.simulateRobot, app.qHome, app.handle);

            % Update information to the main gui:
            app.MainAppHandle.tm5Robot.model.animate(app.qHome);
            app.MainAppHandle.UpdateJointStateData();
            app.MainAppHandle.UpdateEndEffectorData();

            % Update to the Slider Value:
            app.UpdateEndEffectorDisplay;
            app.UpdateSliderValueDisplay;

            drawnow;
        end
        %% RotateEndEffectorByAngle
        % Input: angle: the value of angle that need to rotate (in degree)
        % Input: axes: 1 => rotate by roll angle
        % Input: axes: 2 => rotate by pitch angle
        % Input: axes: 3 => rotate by yaw angle

        function RotateEndEffectorByAngle(app, angle, axes)
            % Get the current end effector pose:
            eeTr = app.simulateRobot.model.fkine(app.qHome).T;

            % Get the orientation
            startOrientation = eeTr(1:3, 1:3);

            % Create a rotation matrix:
            theta = deg2rad(angle);

            if axes == 1
                rotationMatrix = [1,0,0; 0, cos(theta), -sin(theta); 0, sin(theta), cos(theta)];
            elseif axes == 2
                rotationMatrix = [cos(theta), 0, sin(theta); 0, 1, 0; -sin(theta), 0, cos(theta)];
            elseif axes == 3
                rotationMatrix = [cos(theta), -sin(theta), 0; sin(theta), cos(theta), 0; 0, 0, 1];
            end

            % Compute the final desired orientation
            endOrientation = startOrientation * rotationMatrix;

            % Call the function
            app.ControlByEndEffectorOrientation(startOrientation, endOrientation);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainGuiHandle, qHome)
            app.MainAppHandle = mainGuiHandle;
            app.qHome = qHome;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

            % Get the switch value:
            switchValue = app.Switch.Value;

            % Disable the slider and other components when switch is on
            if strcmp(switchValue, 'On')
                
                % -- Array of component names
                components = ["BaseSlider", "ShoulderSlider", "ElbowSlider", "Wrist1Slider", "Wrist2Slider", "Wrist3Slider", "HomingButton", "JSSwitch", "TCPSwitch"];
                buttons = "Button_" + string(1:12);
                controlButtons = ["XButton", "YButton", "XButton_2", "YButton_2", "ZButton", "ZButton_2"];
                controlButtons2 = ["PButton", "PButton_2", "YButton_3", "YButton_4", "RButton", "RButton_2"];
                controlButtons3 = ["WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton_2"];

                % -- Set components and buttons to 'off'
                for comp = [components, buttons, controlButtons, controlButtons2, controlButtons3]
                    app.(comp).Enable = "off";
                end
                
                % -- Update status of the app:
                app.Lamp.Color = [0, 0, 1];
                app.EditField.Value = 'Joy Stick Mode';

                % -- Call the control by Joy Stick Function:
                app.ControlByJoyStick();
            else
                
                % -- Update status of the app:
                app.Lamp.Color = [0, 1, 0];
                app.EditField.Value = 'Simulation Mode';
                
                % -- Update the slider value:
                app.UpdateSliderValue();

                % -- Array of component names:
                components = ["BaseSlider", "ShoulderSlider", "ElbowSlider", "Wrist1Slider", "Wrist2Slider", "Wrist3Slider", "HomingButton", "JSSwitch", "TCPSwitch"];
                buttons = "Button_" + string(1:12);
                controlButtons = ["XButton", "YButton", "XButton_2", "YButton_2", "ZButton", "ZButton_2"];
                controlButtons2 = ["PButton", "PButton_2", "YButton_3", "YButton_4", "RButton", "RButton_2"];
                controlButtons3 = ["WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton_2"];

                % -- Set components and buttons to 'on'
                for comp = [components, buttons, controlButtons, controlButtons2, controlButtons3]
                    app.(comp).Enable = "on";
                end
            end

        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            % --- Setup the axis of the app:
            axes(app.UIAxes);
            cla(app.UIAxes);

            % --- Create a robot model when the button is pressed:
            hFig2 = figure(2);
            ax = axes(hFig2);

            app.simulateRobot = TM5(eye(4), app.qHome, [-1.5 1.5, -1.5, 1.5, 0, 2]);
            hold on
            drawnow

            % --- Update the data of the robot when the robot is called:
            app.UpdateEndEffectorDisplay;

            % --- Set up the slider limit based on the robot limit:
            % Get the joint limit of the robot:
            jointLimits = rad2deg(app.simulateRobot.model.qlim);

            % Update the Slider Limits based on the joint limit of the robot:
            app.BaseSlider.Limits = jointLimits(1,:);       % Set the limits for the Base joint
            app.ShoulderSlider.Limits = jointLimits(2,:);   % Set the limits for the Shoulder joint
            app.ElbowSlider.Limits = jointLimits(3,:);      % Set the limits for the Elbow joint
            app.Wrist1Slider.Limits = jointLimits(4,:);     % Set the limits for the Wrist 1 joint
            app.Wrist2Slider.Limits = jointLimits(5,:);     % Set the limits for the Wrist 2 joint
            app.Wrist3Slider.Limits = jointLimits(6,:);     % Set the limits for the Wrist 3 joint

            % Update the Slider Value at the initial stage:
            % Get the current q:
            currentQ = rad2deg(app.qHome);

            % Update the Slider Value from the current joint stage:
            app.BaseSlider.Value = currentQ(1);
            app.ShoulderSlider.Value = currentQ(2);
            app.ElbowSlider.Value = currentQ(3);
            app.Wrist1Slider.Value = currentQ(4);
            app.Wrist2Slider.Value = currentQ(5);
            app.Wrist3Slider.Value = currentQ(6);

            % Update the value to the label:
            app.UpdateSliderValueDisplay;

            % --- Turn on the switch:
            app.Switch.Enable = 'on';

            % --- Turn on the end effector control button:
            controlButtonNames = {'HomingButton','XButton', 'YButton', 'ZButton', 'XButton_2', 'YButton_2', ...
                'ZButton_2', 'PButton_2', 'YButton_3', 'YButton_4', 'PButton', ...
                'RButton', 'RButton_2', "WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton_2"};

            for i = 1:length(controlButtonNames)
                app.(controlButtonNames{i}).Enable = 'on';
            end

            % --- Display the robot in the axes of the app:
            % - Delete the green floor surface:
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end

            % - Transfer all the children across to the uiaxes parent EXCEPT the green floors
            while 0 < length(ax.Children)
                set(ax.Children(1),'Parent',app.UIAxes);
            end

            delete(get(gca,'parent'))

            % Scale the axes sensibly
            app.UIAxes.DataAspectRatio = [1,1,1];
            app.UIAxes.View = [45,45];

            % Adjust the axes limits
            app.UIAxes.XLim = [-0.5, 0.5];
            app.UIAxes.YLim = [-0.5, 0.5];
            app.UIAxes.ZLim = [-0.3, 1];

            % Turn off this load button and enable the move button
            app.STARTButton.Enable = "off";

            % Turn on the control slider:
            app.JSSwitch.Enable = "on";
            app.TCPSwitch.Enable ="on";

            % Change the lamp:
            app.Lamp.Color = [0, 1, 0];
            app.EditField.Value = 'Simulation Mode';

        end

        % Value changed function: BaseSlider
        function BaseSliderValueChanged(app, event)
            % Get the value to the slider to the q of the robot:
            app.qHome(1) = deg2rad(app.BaseSlider.Value);
            % Update the value to the slider:
            app.UpdateSliderValueDisplay();
        end

        % Value changed function: ShoulderSlider
        function ShoulderSliderValueChanged(app, event)
            % Get the value to the slider to the q of the robot:
            app.qHome(2) = deg2rad(app.ShoulderSlider.Value);
            % Update the value to the slider:
            app.UpdateSliderValueDisplay();
        end

        % Value changed function: ElbowSlider
        function ElbowSliderValueChanged(app, event)
            % Get the value to the slider to the q of the robot:
            app.qHome(3) = deg2rad(app.ElbowSlider.Value);
            % Update the value to the slider:
            app.UpdateSliderValueDisplay();
        end

        % Value changed function: Wrist1Slider
        function Wrist1SliderValueChanged(app, event)
            % Get the value to the slider to the q of the robot:
            app.qHome(4) = deg2rad(app.Wrist1Slider.Value);
            % Update the value to the slider:
            app.UpdateSliderValueDisplay();
        end

        % Value changed function: Wrist2Slider
        function Wrist2SliderValueChanged(app, event)
            % Get the value to the slider to the q of the robot:
            app.qHome(5) = deg2rad(app.Wrist2Slider.Value);
            % Update the value to the slider:
            app.UpdateSliderValueDisplay();
        end

        % Value changed function: Wrist3Slider
        function Wrist3SliderValueChanged(app, event)
            % Get the value to the slider to the q of the robot:
            app.qHome(6) = deg2rad(app.Wrist3Slider.Value);
            % Update the value to the slider:
            app.UpdateSliderValueDisplay();
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            if app.BaseSlider.Value < app.BaseSlider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.BaseSlider.Value = app.BaseSlider.Value + 1;
                app.ChangeSlider(app.BaseSlider.Value, 1);
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.BaseSlider.Value > app.BaseSlider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.BaseSlider.Value = app.BaseSlider.Value - 1;
                app.ChangeSlider(app.BaseSlider.Value, 1);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.ShoulderSlider.Value < app.ShoulderSlider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.ShoulderSlider.Value = app.ShoulderSlider.Value + 1;
                app.ChangeSlider(app.ShoulderSlider.Value, 2);
            end
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.ShoulderSlider.Value > app.ShoulderSlider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.ShoulderSlider.Value = app.ShoulderSlider.Value - 1;
                app.ChangeSlider(app.ShoulderSlider.Value, 2);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.ElbowSlider.Value < app.ElbowSlider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.ElbowSlider.Value = app.ElbowSlider.Value + 1;
                app.ChangeSlider(app.ElbowSlider.Value, 3);
            end
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.ElbowSlider.Value > app.ElbowSlider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.ElbowSlider.Value = app.ElbowSlider.Value - 1;
                app.ChangeSlider(app.ElbowSlider.Value, 3);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.Wrist1Slider.Value < app.Wrist1Slider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist1Slider.Value = app.Wrist1Slider.Value + 1;
                app.ChangeSlider(app.Wrist1Slider.Value, 4);
            end
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            if app.Wrist1Slider.Value > app.Wrist1Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist1Slider.Value = app.Wrist1Slider.Value - 1;
                app.ChangeSlider(app.Wrist1Slider.Value, 4);
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.Wrist2Slider.Value < app.Wrist2Slider.Limits(2) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist2Slider.Value = app.Wrist2Slider.Value + 1;
                app.ChangeSlider(app.Wrist2Slider.Value, 5);
            end
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            if app.Wrist2Slider.Value > app.Wrist2Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist2Slider.Value = app.Wrist2Slider.Value - 1;
                app.ChangeSlider(app.Wrist2Slider.Value, 5);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.Wrist3Slider.Value > app.Wrist3Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist3Slider.Value = app.Wrist3Slider.Value + 1;
                app.ChangeSlider(app.Wrist3Slider.Value, 6);
            end
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            if app.Wrist3Slider.Value > app.Wrist3Slider.Limits(1) && strcmp(app.STARTButton.Enable, 'off')
                app.Wrist3Slider.Value = app.Wrist3Slider.Value - 1;
                app.ChangeSlider(app.Wrist3Slider.Value, 6);
            end
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            % Get the forward Kinematic
            tr = app.simulateRobot.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            XDirection = tr(1:3,1)';
            point2 = point1 + 0.01*XDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            % Get the forward Kinematic
            tr = app.simulateRobot.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            XDirection = tr(1:3,1)';
            point2 = point1 - 0.01*XDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            % Get the forward Kinematic
            tr = app.simulateRobot.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            YDirection = tr(1:3,2)';
            point2 = point1 - 0.01*YDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            % Get the forward Kinematic
            tr = app.simulateRobot.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            YDirection = tr(1:3,2)';
            point2 = point1 + 0.01*YDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            % Get the forward Kinematic
            tr = app.simulateRobot.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            ZDirection = tr(1:3,3)';
            point2 = point1 + 0.01*ZDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            % Get the forward Kinematic
            tr = app.simulateRobot.model.fkine(app.qHome).T;
            point1 = tr((1:3),4)';
            ZDirection = tr(1:3,3)';
            point2 = point1 - 0.01*ZDirection;

            app.ControlByEndEffectorPosition(point1, point2);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            app.RotateEndEffectorByAngle(5, 1);
        end

        % Button pushed function: RButton_2
        function RButton_2Pushed(app, event)
            app.RotateEndEffectorByAngle(-5, 1);
        end

        % Button pushed function: PButton
        function PButtonPushed(app, event)
            app.RotateEndEffectorByAngle(5, 2);
        end

        % Button pushed function: PButton_2
        function PButton_2Pushed(app, event)
            app.RotateEndEffectorByAngle(-5, 2);
        end

        % Button pushed function: YButton_3
        function YButton_3Pushed(app, event)
            app.RotateEndEffectorByAngle(5, 3);
        end

        % Button pushed function: YButton_4
        function YButton_4Pushed(app, event)
            app.RotateEndEffectorByAngle(-5, 3);
        end

        % Button pushed function: VxButton
        function VxButtonPushed(app, event)
            app.ControlByCatersian(0.2, 0, 0, 0, 0, 0);
        end

        % Button pushed function: VxButton_2
        function VxButton_2Pushed(app, event)
            app.ControlByCatersian(-0.2, 0, 0, 0, 0, 0);
        end

        % Button pushed function: VyButton
        function VyButtonPushed(app, event)
            app.ControlByCatersian(0, 0.2, 0, 0, 0, 0);
        end

        % Button pushed function: VyButton_2
        function VyButton_2Pushed(app, event)
            app.ControlByCatersian(0, -0.2, 0, 0, 0, 0);
        end

        % Button pushed function: VzButton
        function VzButtonPushed(app, event)
            app.ControlByCatersian(0, 0, 0.2, 0, 0, 0);
        end

        % Button pushed function: VzButton_2
        function VzButton_2Pushed(app, event)
            app.ControlByCatersian(0, 0, -0.2, 0, 0, 0);
        end

        % Callback function
        function WxButtonPushed(app, event)
            app.ControlByCatersian(0, 0, 0, 0.2, 0, 0);
        end

        % Button pushed function: WyButton
        function WyButtonPushed(app, event)
            app.ControlByCatersian(0, 0, 0, 0, 0.2, 0);
        end

        % Button pushed function: WxButton
        function WxButtonPushed2(app, event)
            app.ControlByCatersian(0, 0, 0, 0.2, 0, 0);
        end

        % Button pushed function: WxButton_2
        function WxButton_2Pushed(app, event)
            app.ControlByCatersian(0, 0, 0, -0.2, 0, 0);
        end

        % Button pushed function: WyButton_2
        function WyButton_2Pushed(app, event)
            app.ControlByCatersian(0, 0, 0, 0, -0.2, 0);
        end

        % Button pushed function: WzButton
        function WzButtonPushed(app, event)
            app.ControlByCatersian(0, 0, 0, 0, 0, 0.2);
        end

        % Button pushed function: WzButton_2
        function WzButton_2Pushed(app, event)
            app.ControlByCatersian(0, 0, 0, 0, 0, -0.2);
        end

        % Button pushed function: HomingButton
        function HomingButtonPushed(app, event)
            % --- Reset the Warning when begin the function:
            app.EditField_2.Value = sprintf('');

            % --- Change the Lamp colour to green:
            app.Lamp.Color = [0 1 0];

            %% If reset is pressed set up the robot back to the home:
            qDesired = [0, -pi/2, -pi/2, -pi/2, pi/2, 0];
            
            % Turn on the E-stop button to ensure safety:
            app.EStopButton.Enable = 'on';

            % Create a path to homing position:
            path = jtraj(app.qHome, qDesired, 50);

            % Animate the path
            for i = 1:size(path,1)
                % -- Animate the robot:
                app.FindHandleTM5();
                app.AnimateModfiled(app.simulateRobot, path(i,:), app.handle);

                % -- Update information to the main gui:
                app.MainAppHandle.tm5Robot.model.animate(path(i,:));
                app.MainAppHandle.UpdateJointStateData();
                app.MainAppHandle.UpdateEndEffectorData();

                % -- Update qHome:
                app.qHome = path(i,:);

                % -- Check ground collision:
                if app.simulateRobot.GroundCollision(path(i,:)) == true
                    break;

                    % -- Check the e-stop flag:
                elseif app.eStopFlag == true
                    app.Lamp.Color = [1 0 0];
                    app.EditField_2.Value = sprintf('E-Stop Pressed!');
                    break;
                end
                
                % -- Update the data to the Gui:
                app.UpdateEndEffectorDisplay;
                app.UpdateSliderValue;
                app.UpdateSliderValueDisplay;

                % -- Delay a little bit:
                pause(0.05);
            end
            
            % Reset the eStop flag:
            app.eStopFlag = false;
            
            % Turn off the E-stop button after homing finished:
            app.EStopButton.Enable = 'off';

            % Update the slider value:
            app.UpdateEndEffectorDisplay;
            app.UpdateSliderValue();
            app.UpdateSliderValueDisplay();

        end

        % Callback function
        function TM5ButtonPushed(app, event)
            app.robotOption = 1;
            app.EditField.Value = 'You Choose: TM5';

            % Activate the Start Button:
            app.STARTButton.Enable = "on";

            % Turn off the option:
            app.TM5Button.Enable = "off";
            app.TM12Button.Enable = "off";
        end

        % Callback function
        function TM12ButtonPushed(app, event)
            app.robotOption = 2;
            app.EditField.Value = 'You Choose: UR5';
            % Activate the Start Button:
            app.STARTButton.Enable = "on";

            % Turn off the option:
            app.TM5Button.Enable = "off";
            app.TM12Button.Enable = "off";
        end

        % Value changed function: JSSwitch
        function JSSwitchValueChanged(app, event)
            value = app.JSSwitch.Value;
            
            if(strcmp(value ,'On'))
                % Turn on the slider component:
                components = {'BaseSlider', 'ShoulderSlider', 'ElbowSlider', 'Wrist1Slider', 'Wrist2Slider', 'Wrist3Slider', 'PlanButton'};

                for i = 1:length(components)
                    if isprop(app, components{i}) && isvalid(app.(components{i}))
                        app.(components{i}).Enable = 'on';
                    end
                end

                % Turn off the button when in the planning mode:
                controlButtonNames = {'Switch','HomingButton','XButton', 'YButton', 'ZButton', 'XButton_2', 'YButton_2', ...
                    'ZButton_2', 'PButton_2', 'YButton_3', 'YButton_4', 'PButton', 'Button_1', 'Button_2', 'Button_3', 'Button_4', 'Button_5', 'Button_6', 'Button_7', 'Button_8', 'Button_9', 'Button_10', 'Button_11', 'Button_12'...
                    'RButton', 'RButton_2', "WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton", "VzButton_2", "TCPSwitch"};

                for i = 1:length(controlButtonNames)
                    app.(controlButtonNames{i}).Enable = 'off';
                end

            else
                % Turn on the slider component:
                components = {'BaseSlider', 'ShoulderSlider', 'ElbowSlider', 'Wrist1Slider', 'Wrist2Slider', 'Wrist3Slider', 'PlanButton'};

                for i = 1:length(components)
                    if isprop(app, components{i}) && isvalid(app.(components{i}))
                        app.(components{i}).Enable = 'off';
                    end
                end

                % Turn off the button when in the planning mode:
                controlButtonNames = {'Switch', 'HomingButton','XButton', 'YButton', 'ZButton', 'XButton_2', 'YButton_2', ...
                    'ZButton_2', 'PButton_2', 'YButton_3', 'YButton_4', 'PButton', 'Button_1', 'Button_2', 'Button_3', 'Button_4', 'Button_5', 'Button_6', 'Button_7', 'Button_8', 'Button_9', 'Button_10', 'Button_11', 'Button_12'...
                    'RButton', 'RButton_2', "WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton", "VzButton_2", "TCPSwitch"};

                for i = 1:length(controlButtonNames)
                    app.(controlButtonNames{i}).Enable = 'on';
                end

            end
        end

        % Size changed function: TM5Tab
        function TM5TabSizeChanged(app, event)

            
        end

        % Button pushed function: PlanButton
        function PlanButtonPushed(app, event)
            % Turn on the E-stop button to ensure safety:
            app.EStopButton.Enable = 'on';
            
            % --- Reset the Warning when begin the function:
            app.EditField_2.Value = sprintf('');

            % --- Change the Lamp colour to green:
            app.Lamp.Color = [0 1 0];

            % If the app is in the Joint State mode:
            if strcmp(app.JSSwitch.Value, 'On')
                % Create a jtraj to the setup position:
                path = jtraj(app.MainAppHandle.tm5Robot.model.getpos, app.qHome, 50);
    
                % Animate the path
                for i = 1:length(path)
                    % Check ground collision:
                    if (app.simulateRobot.GroundCollision(path(i,:)) == true)
                        % When collision occur, update the current position:
                        app.qHome = path(i,:);
    
                        % Update Data to main gui:
                        app.MainAppHandle.UpdateJointStateData();
                        app.MainAppHandle.UpdateEndEffectorData();
    
                        % Update data:
                        app.UpdateEndEffectorDisplay;
                        app.UpdateSliderValueDisplay;
                        app.UpdateSliderValue;

                        % Update Warning to user:
                        app.Lamp.Color = [1 0 0];
                        app.EditField_2.Value = sprintf('Collision!');
                        break;
                    
                    elseif app.eStopFlag == true

                        % When e-stop is pressed, update the current position:
                        app.qHome = path(i,:);

                        % Update data:
                        app.UpdateEndEffectorDisplay;
                        app.UpdateSliderValueDisplay;
                        app.UpdateSliderValue;
                        
                        % Update Data to main gui:
                        app.MainAppHandle.UpdateJointStateData();
                        app.MainAppHandle.UpdateEndEffectorData();

                        % Update Warning to user:
                        app.Lamp.Color = [1 0 0];
                        app.EditField_2.Value = sprintf('E-Stop Pressed!');
                        break;
                    end
    
                    % Animate the q:
                    app.FindHandleTM5()
                    app.AnimateModfiled(app.simulateRobot, path(i,:), app.handle);
                    app.UpdateEndEffectorDisplay;
    
                    % Update information to the main gui:
                    app.MainAppHandle.tm5Robot.model.animate(path(i,:));
                    app.MainAppHandle.UpdateJointStateData();
                    app.MainAppHandle.UpdateEndEffectorData();
                    
                    % Setup the delay:
                    pause(0.05);
                end

                % Reset the flag after breaking the function:
                app.eStopFlag = false;
            
            % If the app is in the TCP mode:
            elseif strcmp(app.TCPSwitch.Value, 'On')
                
                % --- Get the value from the Input Edit text
                inputX = app.InputXEditField.Value;
                inputY = app.InputYEditField.Value;
                inputZ = app.InputZEditField.Value;
                
                % --- Perform RMRC from the Input value:
                
                % - Get the current and desired end effector pose:
                eeTr = app.simulateRobot.model.fkine(app.qHome).T;
                desiredPose = eeTr*transl(inputX, inputY, inputZ);
                
                % - Get the current point:
                point1 = eeTr(1:3, 4)';
                point2 = desiredPose(1:3, 4)';

                % - Begin the RMRC function:
                app.ControlByEndEffectorPosition(point1, point2, 0.06, 50);

                % - Reset the edit field when the function is finished:
                app.InputXEditField.Value = 0;
                app.InputYEditField.Value = 0;
                app.InputZEditField.Value = 0;

            end

            % Turn off the E-stop button after mission finished:
            app.EStopButton.Enable = 'off';

        end

        % Value changed function: TCPSwitch
        function TCPSwitchValueChanged(app, event)
            value = app.TCPSwitch.Value;
            if(strcmp(value ,'On'))
                % Turn on the slider component:
                components = {'InputXEditField', 'InputYEditField', 'InputZEditField', 'PlanButton'};

                for i = 1:length(components)
                    if isprop(app, components{i}) && isvalid(app.(components{i}))
                        app.(components{i}).Enable = 'on';
                    end
                end

                % Turn off the button when in the planning mode:
                controlButtonNames = {'Switch','HomingButton','XButton', 'YButton', 'ZButton', 'XButton_2', 'YButton_2', ...
                    'ZButton_2', 'PButton_2', 'YButton_3', 'YButton_4', 'PButton', 'Button_1', 'Button_2', 'Button_3', 'Button_4', 'Button_5', 'Button_6', 'Button_7', 'Button_8', 'Button_9', 'Button_10', 'Button_11', 'Button_12'...
                    'RButton', 'RButton_2', "WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton", "VzButton_2", "JSSwitch"};

                for i = 1:length(controlButtonNames)
                    app.(controlButtonNames{i}).Enable = 'off';
                end

            else
                % Turn off the slider component if the Switch is turn off:
                components = {'InputXEditField', 'InputYEditField', 'InputZEditField', 'PlanButton'};

                for i = 1:length(components)
                    if isprop(app, components{i}) && isvalid(app.(components{i}))
                        app.(components{i}).Enable = 'off';
                    end
                end

                % Turn off the button when in the planning mode:
                controlButtonNames = {'Switch', 'HomingButton','XButton', 'YButton', 'ZButton', 'XButton_2', 'YButton_2', ...
                    'ZButton_2', 'PButton_2', 'YButton_3', 'YButton_4', 'PButton', 'Button_1', 'Button_2', 'Button_3', 'Button_4', 'Button_5', 'Button_6', 'Button_7', 'Button_8', 'Button_9', 'Button_10', 'Button_11', 'Button_12'...
                    'RButton', 'RButton_2', "WzButton", "WzButton_2", "WxButton", "WxButton_2", "WyButton", "WyButton_2", "VxButton", "VyButton", "VzButton", "VxButton_2", "VyButton_2", "VzButton", "VzButton_2", "JSSwitch"};

                for i = 1:length(controlButtonNames)
                    app.(controlButtonNames{i}).Enable = 'on';
                end

            end
        end

        % Value changed function: InputXEditField
        function InputXEditFieldValueChanged(app, event)
  
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            app.eStopFlag = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TM5Tab = uitab(app.TabGroup);
            app.TM5Tab.SizeChangedFcn = createCallbackFcn(app, @TM5TabSizeChanged, true);
            app.TM5Tab.Title = 'TM5';
            app.UIAxes = uiaxes(app.TM5Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TCPPositionPanel = uipanel(app.TM5Tab);
            app.TCPPositionPanel.Title = 'TCP Position';
            app.XButton = uibutton(app.TCPPositionPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Enable = 'off';
            app.XButton.Text = 'X+';
            app.YButton = uibutton(app.TCPPositionPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Enable = 'off';
            app.YButton.Text = 'Y+';
            app.XButton_2 = uibutton(app.TCPPositionPanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Enable = 'off';
            app.XButton_2.Text = 'X-';
            app.ZButton_2 = uibutton(app.TCPPositionPanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Enable = 'off';
            app.ZButton_2.Text = 'Z-';
            app.ZButton = uibutton(app.TCPPositionPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Enable = 'off';
            app.ZButton.Text = 'Z+';
            app.YButton_2 = uibutton(app.TCPPositionPanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Enable = 'off';
            app.YButton_2.Text = 'Y-';
            app.ToolPositionPanel = uipanel(app.TM5Tab);
            app.ToolPositionPanel.Title = 'Tool Position';
            app.XEditField_2Label = uilabel(app.ToolPositionPanel);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.ToolPositionPanel, 'text');
            app.XEditField_2.Editable = 'off';
            app.YEditField_2Label = uilabel(app.ToolPositionPanel);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.ToolPositionPanel, 'text');
            app.YEditField_2.Editable = 'off';
            app.ZEditField_2Label = uilabel(app.ToolPositionPanel);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.ToolPositionPanel, 'text');
            app.ZEditField_2.Editable = 'off';
            app.RollEditFieldLabel = uilabel(app.ToolPositionPanel);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.ToolPositionPanel, 'text');
            app.RollEditField.Editable = 'off';
            app.PitchEditFieldLabel = uilabel(app.ToolPositionPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.ToolPositionPanel, 'text');
            app.PitchEditField.Editable = 'off';
            app.YawEditFieldLabel = uilabel(app.ToolPositionPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.ToolPositionPanel, 'text');
            app.YawEditField.Editable = 'off';
            app.JointPositionPanel = uipanel(app.TM5Tab);
            app.JointPositionPanel.Title = 'Joint Position';
            app.BaseDisplay = uieditfield(app.JointPositionPanel, 'text');
            app.BaseDisplay.Editable = 'off';
            app.ShoulderDisplay = uieditfield(app.JointPositionPanel, 'text');
            app.ShoulderDisplay.Editable = 'off';
            app.ElbowDisplay = uieditfield(app.JointPositionPanel, 'text');
            app.ElbowDisplay.Editable = 'off';
            app.Wrist1Display = uieditfield(app.JointPositionPanel, 'text');
            app.Wrist1Display.Editable = 'off';
            app.Wrist2Display = uieditfield(app.JointPositionPanel, 'text');
            app.Wrist2Display.Editable = 'off';
            app.Wrist3Display = uieditfield(app.JointPositionPanel, 'text');
            app.Wrist3Display.Editable = 'off';
            app.Button_1 = uibutton(app.JointPositionPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '+';
            app.Button_2 = uibutton(app.JointPositionPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.JointPositionPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.JointPositionPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '+';
            app.Button_5 = uibutton(app.JointPositionPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '+';
            app.Button_6 = uibutton(app.JointPositionPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '+';
            app.Button_7 = uibutton(app.JointPositionPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '-';
            app.Button_8 = uibutton(app.JointPositionPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '-';
            app.Button_9 = uibutton(app.JointPositionPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '-';
            app.Button_10 = uibutton(app.JointPositionPanel, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-';
            app.Button_11 = uibutton(app.JointPositionPanel, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-';
            app.Button_12 = uibutton(app.JointPositionPanel, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-';
            app.BaseSliderLabel = uilabel(app.JointPositionPanel);
            app.BaseSliderLabel.Text = 'Base';
            app.BaseSlider = uislider(app.JointPositionPanel);
            app.BaseSlider.ValueChangedFcn = createCallbackFcn(app, @BaseSliderValueChanged, true);
            app.BaseSlider.Enable = 'off';
            app.ShoulderSliderLabel = uilabel(app.JointPositionPanel);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.JointPositionPanel);
            app.ShoulderSlider.ValueChangedFcn = createCallbackFcn(app, @ShoulderSliderValueChanged, true);
            app.ShoulderSlider.Enable = 'off';
            app.ElbowSliderLabel = uilabel(app.JointPositionPanel);
            app.ElbowSliderLabel.Text = 'Elbow';
            app.ElbowSlider = uislider(app.JointPositionPanel);
            app.ElbowSlider.ValueChangedFcn = createCallbackFcn(app, @ElbowSliderValueChanged, true);
            app.ElbowSlider.Enable = 'off';
            app.Wrist1SliderLabel = uilabel(app.JointPositionPanel);
            app.Wrist1SliderLabel.Text = 'Wrist 1';
            app.Wrist1Slider = uislider(app.JointPositionPanel);
            app.Wrist1Slider.ValueChangedFcn = createCallbackFcn(app, @Wrist1SliderValueChanged, true);
            app.Wrist1Slider.Enable = 'off';
            app.Wrist2SliderLabel = uilabel(app.JointPositionPanel);
            app.Wrist2SliderLabel.Text = 'Wrist 2';
            app.Wrist2Slider = uislider(app.JointPositionPanel);
            app.Wrist2Slider.ValueChangedFcn = createCallbackFcn(app, @Wrist2SliderValueChanged, true);
            app.Wrist2Slider.Enable = 'off';
            app.Wrist3SliderLabel = uilabel(app.JointPositionPanel);
            app.Wrist3SliderLabel.Text = 'Wrist 3';
            app.Wrist3Slider = uislider(app.JointPositionPanel);
            app.Wrist3Slider.ValueChangedFcn = createCallbackFcn(app, @Wrist3SliderValueChanged, true);
            app.Wrist3Slider.Enable = 'off';
            app.SwitchLabel = uilabel(app.TM5Tab);
            app.SwitchLabel.Enable = 'off';
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.TM5Tab, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.STARTButton = uibutton(app.TM5Tab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.TCPOrientationPanel = uipanel(app.TM5Tab);
            app.TCPOrientationPanel.Title = 'TCP Orientation';
            app.RButton = uibutton(app.TCPOrientationPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Enable = 'off';
            app.RButton.Text = 'R+';
            app.PButton = uibutton(app.TCPOrientationPanel, 'push');
            app.PButton.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.PButton.Enable = 'off';
            app.PButton.Text = 'P+';
            app.RButton_2 = uibutton(app.TCPOrientationPanel, 'push');
            app.RButton_2.ButtonPushedFcn = createCallbackFcn(app, @RButton_2Pushed, true);
            app.RButton_2.Enable = 'off';
            app.RButton_2.Text = 'R-';
            app.YButton_4 = uibutton(app.TCPOrientationPanel, 'push');
            app.YButton_4.ButtonPushedFcn = createCallbackFcn(app, @YButton_4Pushed, true);
            app.YButton_4.Enable = 'off';
            app.YButton_4.Text = 'Y-';
            app.PButton_2 = uibutton(app.TCPOrientationPanel, 'push');
            app.PButton_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_2Pushed, true);
            app.PButton_2.Enable = 'off';
            app.PButton_2.Text = 'P-';
            app.YButton_3 = uibutton(app.TCPOrientationPanel, 'push');
            app.YButton_3.ButtonPushedFcn = createCallbackFcn(app, @YButton_3Pushed, true);
            app.YButton_3.Enable = 'off';
            app.YButton_3.Text = 'Y+';
            app.CatesianControlPanel = uipanel(app.TM5Tab);
            app.CatesianControlPanel.Title = 'Catesian Control';
            app.VxButton = uibutton(app.CatesianControlPanel, 'push');
            app.VxButton.ButtonPushedFcn = createCallbackFcn(app, @VxButtonPushed, true);
            app.VxButton.Enable = 'off';
            app.VxButton.Text = 'Vx';
            app.VxButton_2 = uibutton(app.CatesianControlPanel, 'push');
            app.VxButton_2.ButtonPushedFcn = createCallbackFcn(app, @VxButton_2Pushed, true);
            app.VxButton_2.Enable = 'off';
            app.VxButton_2.Text = 'Vx-';
            app.VyButton = uibutton(app.CatesianControlPanel, 'push');
            app.VyButton.ButtonPushedFcn = createCallbackFcn(app, @VyButtonPushed, true);
            app.VyButton.Enable = 'off';
            app.VyButton.Text = 'Vy+';
            app.VyButton_2 = uibutton(app.CatesianControlPanel, 'push');
            app.VyButton_2.ButtonPushedFcn = createCallbackFcn(app, @VyButton_2Pushed, true);
            app.VyButton_2.Enable = 'off';
            app.VyButton_2.Text = 'Vy-';
            app.VzButton_2 = uibutton(app.CatesianControlPanel, 'push');
            app.VzButton_2.ButtonPushedFcn = createCallbackFcn(app, @VzButton_2Pushed, true);
            app.VzButton_2.Enable = 'off';
            app.VzButton_2.Text = 'Vz-';
            app.VzButton = uibutton(app.CatesianControlPanel, 'push');
            app.VzButton.ButtonPushedFcn = createCallbackFcn(app, @VzButtonPushed, true);
            app.VzButton.Enable = 'off';
            app.VzButton.Text = 'Vz+';
            app.WyButton = uibutton(app.CatesianControlPanel, 'push');
            app.WyButton.ButtonPushedFcn = createCallbackFcn(app, @WyButtonPushed, true);
            app.WyButton.Enable = 'off';
            app.WyButton.Text = 'Wy';
            app.WxButton_2 = uibutton(app.CatesianControlPanel, 'push');
            app.WxButton_2.ButtonPushedFcn = createCallbackFcn(app, @WxButton_2Pushed, true);
            app.WxButton_2.Enable = 'off';
            app.WxButton_2.Text = 'Wx-';
            app.WxButton = uibutton(app.CatesianControlPanel, 'push');
            app.WxButton.ButtonPushedFcn = createCallbackFcn(app, @WxButtonPushed2, true);
            app.WxButton.Enable = 'off';
            app.WxButton.Text = 'Wx';
            app.WzButton = uibutton(app.CatesianControlPanel, 'push');
            app.WzButton.ButtonPushedFcn = createCallbackFcn(app, @WzButtonPushed, true);
            app.WzButton.Enable = 'off';
            app.WzButton.Text = 'Wz';
            app.WyButton_2 = uibutton(app.CatesianControlPanel, 'push');
            app.WyButton_2.ButtonPushedFcn = createCallbackFcn(app, @WyButton_2Pushed, true);
            app.WyButton_2.Enable = 'off';
            app.WyButton_2.Text = 'Wy-';
            app.WzButton_2 = uibutton(app.CatesianControlPanel, 'push');
            app.WzButton_2.ButtonPushedFcn = createCallbackFcn(app, @WzButton_2Pushed, true);
            app.WzButton_2.Enable = 'off';
            app.WzButton_2.Text = 'Wz-';
            app.InputXEditFieldLabel = uilabel(app.CatesianControlPanel);
            app.InputXEditFieldLabel.Text = 'Input X';
            app.InputXEditField = uieditfield(app.CatesianControlPanel, 'numeric');
            app.InputXEditField.ValueChangedFcn = createCallbackFcn(app, @InputXEditFieldValueChanged, true);
            app.InputXEditField.Enable = 'off';
            app.InputYLabel = uilabel(app.CatesianControlPanel);
            app.InputYLabel.Text = 'Input Y';
            app.InputYEditField = uieditfield(app.CatesianControlPanel, 'numeric');
            app.InputYEditField.Enable = 'off';
            app.InputZEditFieldLabel = uilabel(app.CatesianControlPanel);
            app.InputZEditFieldLabel.Text = 'Input Z';
            app.InputZEditField = uieditfield(app.CatesianControlPanel, 'numeric');
            app.InputZEditField.Enable = 'off';
            app.HomingButton = uibutton(app.TM5Tab, 'push');
            app.HomingButton.ButtonPushedFcn = createCallbackFcn(app, @HomingButtonPushed, true);
            app.HomingButton.Enable = 'off';
            app.HomingButton.Text = 'Homing';
            app.StatusLabel = uilabel(app.TM5Tab);
            app.StatusLabel.Text = 'Status';
            app.Lamp = uilamp(app.TM5Tab);
            app.EditField = uieditfield(app.TM5Tab, 'text');
            app.EditField.Editable = 'off';
            app.JSSwitchLabel = uilabel(app.TM5Tab);
            app.JSSwitchLabel.Text = 'JS';
            app.JSSwitch = uiswitch(app.TM5Tab, 'toggle');
            app.JSSwitch.ValueChangedFcn = createCallbackFcn(app, @JSSwitchValueChanged, true);
            app.JSSwitch.Enable = 'off';
            app.PlanButton = uibutton(app.TM5Tab, 'push');
            app.PlanButton.ButtonPushedFcn = createCallbackFcn(app, @PlanButtonPushed, true);
            app.PlanButton.Enable = 'off';
            app.PlanButton.Text = 'Plan';
            app.TCPSwitchLabel = uilabel(app.TM5Tab);
            app.TCPSwitchLabel.Text = 'TCP';
            app.TCPSwitch = uiswitch(app.TM5Tab, 'toggle');
            app.TCPSwitch.ValueChangedFcn = createCallbackFcn(app, @TCPSwitchValueChanged, true);
            app.TCPSwitch.Enable = 'off';
            app.EditField_2 = uieditfield(app.TM5Tab, 'text');
            app.EditField_2.Editable = 'off';
            app.EStopButton = uibutton(app.TM5Tab, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Enable = 'off';
            app.EStopButton.Text = 'E-Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeachPendant(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end